classdef Primero < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GlauDTect                   matlab.ui.Figure
        Modulos                     matlab.ui.container.TabGroup
        IniciarSesion               matlab.ui.container.Tab
        ButtonLimpiar               matlab.ui.control.Button
        ButtonInicioSesion          matlab.ui.control.Button
        ImgCorrecto                 matlab.ui.control.Image
        ImgError                    matlab.ui.control.Image
        EditFieldCorrecto           matlab.ui.control.Label
        EditFieldError              matlab.ui.control.Label
        ImgContrasena               matlab.ui.control.Image
        EditFieldContrasena         matlab.ui.control.EditField
        Ast3                        matlab.ui.control.Label
        ImgCorreo                   matlab.ui.control.Image
        ImgID                       matlab.ui.control.Image
        EditFieldCorreo             matlab.ui.control.EditField
        EditFieldID                 matlab.ui.control.EditField
        Ast2                        matlab.ui.control.Label
        ImgNombreApellidos          matlab.ui.control.Image
        EditFieldNombreApellidos    matlab.ui.control.EditField
        Ast1                        matlab.ui.control.Label
        LabelInicioDeSesion         matlab.ui.control.Label
        ImgLogo1                    matlab.ui.control.Image
        LabelBienvenido             matlab.ui.control.Label
        HTMLCuadrado                matlab.ui.control.HTML
        LabelBordeInferior          matlab.ui.control.Label
        LabelInferior               matlab.ui.control.Label
        LabelBordeSuperior          matlab.ui.control.Label
        LabelSuperior               matlab.ui.control.Label
        ImgFondo                    matlab.ui.control.Image
        Instrucciones               matlab.ui.container.Tab
        LabelInstrucciones1         matlab.ui.control.Label
        LabelInstrucciones2         matlab.ui.control.Label
        LabelInstrucciones3         matlab.ui.control.Label
        HyperlinkInstrucciones      matlab.ui.control.Hyperlink
        LabelInstrucciones4         matlab.ui.control.Label
        ImgInstrucciones            matlab.ui.control.Image
        HTMLRectInstrucciones       matlab.ui.control.HTML
        LabelPaginaWeb              matlab.ui.control.Label
        ImgPaginaWeb                matlab.ui.control.Image
        LabelCerrarApp              matlab.ui.control.Label
        ImgCandado                  matlab.ui.control.Image
        HTMLFechaHora               matlab.ui.control.HTML
        LabelID                     matlab.ui.control.Label
        LabelDoctor                 matlab.ui.control.Label
        ImgDoctor                   matlab.ui.control.Image
        LabelAcercaDe               matlab.ui.control.Label
        ButtonAcercaDe              matlab.ui.control.Button
        LabelInf                    matlab.ui.control.Label
        ButtonInforme               matlab.ui.control.Button
        LabelGlaucoma               matlab.ui.control.Label
        ButtonGlaucoma              matlab.ui.control.Button
        LabelInstr                  matlab.ui.control.Label
        ButtonInstr                 matlab.ui.control.Button
        LabelLogIn                  matlab.ui.control.Label
        ButtonLogIn                 matlab.ui.control.Button
        ImgLogo2                    matlab.ui.control.Image
        LabelBordeInferior2         matlab.ui.control.Label
        LabelInferior2              matlab.ui.control.Label
        LabelBordeSuperior2         matlab.ui.control.Label
        LabelSuperior2              matlab.ui.control.Label
        DeteccionGlaucoma           matlab.ui.container.Tab
        ButtonSegmentar_3           matlab.ui.control.Button
        LabelProbabilidad           matlab.ui.control.Label
        DiscopticoDropDown          matlab.ui.control.DropDown
        LampDiagnostico             matlab.ui.control.Lamp
        LabelDiagnostico            matlab.ui.control.Label
        ButtonSegmentar_2           matlab.ui.control.Button
        ButtonTrash                 matlab.ui.control.Button
        ButtonSegmentar             matlab.ui.control.Button
        HTMLRect2_2                 matlab.ui.control.HTML
        ImgOD                       matlab.ui.control.Image
        MximoLaplacianaLabel_3      matlab.ui.control.Label
        MximoLaplacianaLabel_2      matlab.ui.control.Label
        MximoLaplacianaLabel        matlab.ui.control.Label
        ContrasteEditField          matlab.ui.control.EditField
        ContrasteLabel              matlab.ui.control.Label
        RangoDinamicoEditField      matlab.ui.control.EditField
        RangoDinmicoLabel           matlab.ui.control.Label
        EntropiaEditField           matlab.ui.control.EditField
        EntropiaLabel               matlab.ui.control.Label
        LapDesEditField             matlab.ui.control.EditField
        LapMedEditField             matlab.ui.control.EditField
        LapMaxEditField             matlab.ui.control.EditField
        LampCalidad                 matlab.ui.control.Lamp
        LabelCalidad                matlab.ui.control.Label
        ButtonDeterminarCalidad     matlab.ui.control.Button
        ButtonAnadirImagen          matlab.ui.control.Button
        ImgCarga                    matlab.ui.control.Image
        EditFieldCarga              matlab.ui.control.Label
        ButtonAnadirRepositorio     matlab.ui.control.Button
        TreeRepositorio             matlab.ui.container.Tree
        NodeRepositorio             matlab.ui.container.TreeNode
        EditFieldImagen             matlab.ui.control.Label
        ImgRFI                      matlab.ui.control.Image
        Lamp                        matlab.ui.control.Lamp
        ElegirCanaldeimagenLabel    matlab.ui.control.Label
        DropDownElegirCanal         matlab.ui.control.DropDown
        LabelPaginaWeb2             matlab.ui.control.Label
        ImgPaginaWeb2               matlab.ui.control.Image
        LabelCerrarApp2             matlab.ui.control.Label
        ImgCandado2                 matlab.ui.control.Image
        HTMLFechaHora2              matlab.ui.control.HTML
        LabelID2                    matlab.ui.control.Label
        LabelDoctor2                matlab.ui.control.Label
        ImgDoctor2                  matlab.ui.control.Image
        LabelAcercaDe2              matlab.ui.control.Label
        ButtonAcercaDe2             matlab.ui.control.Button
        LabelInf2                   matlab.ui.control.Label
        ButtonInforme2              matlab.ui.control.Button
        LabelGlaucoma2              matlab.ui.control.Label
        ButtonGlaucoma2             matlab.ui.control.Button
        LabelInstr2                 matlab.ui.control.Label
        ButtonInstr2                matlab.ui.control.Button
        LabelLogIn2                 matlab.ui.control.Label
        ButtonLogIn2                matlab.ui.control.Button
        HTMLRect2                   matlab.ui.control.HTML
        ImgLogo3                    matlab.ui.control.Image
        LabelBordeInferior3         matlab.ui.control.Label
        LabelInferior3              matlab.ui.control.Label
        LabelBordeSuperior3         matlab.ui.control.Label
        LabelSuperior3              matlab.ui.control.Label
        Histogram                   matlab.ui.control.UIAxes
        CrearInforme                matlab.ui.container.Tab
        LabelComentarios            matlab.ui.control.Label
        TextAreaComentarios         matlab.ui.control.TextArea
        ImgComentarios              matlab.ui.control.Image
        ButtonGuardarInforme        matlab.ui.control.Button
        ImgFecha                    matlab.ui.control.Image
        DatePickerFecha             matlab.ui.control.DatePicker
        FechaDatePickerLabel        matlab.ui.control.Label
        ImgEspecialista             matlab.ui.control.Image
        EditFieldEspecialista       matlab.ui.control.EditField
        EspecialistaEditFieldLabel  matlab.ui.control.Label
        ImgImagen                   matlab.ui.control.Image
        ImagenEditField             matlab.ui.control.EditField
        ImagenEditFieldLabel        matlab.ui.control.Label
        ButtonIniciarInforme        matlab.ui.control.Button
        LabelInforme2               matlab.ui.control.Label
        LabelInforme1               matlab.ui.control.Label
        ImgInforme                  matlab.ui.control.Image
        LabelPaginaWeb3             matlab.ui.control.Label
        ImgPaginaWeb3               matlab.ui.control.Image
        LabelCerrarApp3             matlab.ui.control.Label
        ImgCandado3                 matlab.ui.control.Image
        HTMLFechaHora3              matlab.ui.control.HTML
        LabelID3                    matlab.ui.control.Label
        LabelDoctor3                matlab.ui.control.Label
        ImgDoctor3                  matlab.ui.control.Image
        LabelAcercaDe3              matlab.ui.control.Label
        ButtonAcercaDe3             matlab.ui.control.Button
        LabelInforme3               matlab.ui.control.Label
        ButtonInforme3              matlab.ui.control.Button
        LabelGlaucoma3              matlab.ui.control.Label
        ButtonGlaucoma3             matlab.ui.control.Button
        LabelInstr3                 matlab.ui.control.Label
        ButtonInstr3                matlab.ui.control.Button
        LabelLogIn3                 matlab.ui.control.Label
        ButtonLogIn3                matlab.ui.control.Button
        HTMLRectInforme             matlab.ui.control.HTML
        ImgLogo4                    matlab.ui.control.Image
        LabelBordeInferior4         matlab.ui.control.Label
        LabelInferior4              matlab.ui.control.Label
        LabelBordeSuperior4         matlab.ui.control.Label
        LabelSuperior4              matlab.ui.control.Label
        AcercaDe                    matlab.ui.container.Tab
        ButtonMasInfo               matlab.ui.control.Button
        LabelMasInfo                matlab.ui.control.Label
        LabelOier                   matlab.ui.control.Label
        ImgOier                     matlab.ui.control.Image
        LabelJonan                  matlab.ui.control.Label
        ImgJonan                    matlab.ui.control.Image
        LabelKimetz                 matlab.ui.control.Label
        ImgKimetz                   matlab.ui.control.Image
        LabelMarkelP                matlab.ui.control.Label
        ImgMarkelP                  matlab.ui.control.Image
        LabelMarkelO                matlab.ui.control.Label
        ImgMarkelO                  matlab.ui.control.Image
        LabelIbai                   matlab.ui.control.Label
        ImgIbai                     matlab.ui.control.Image
        LabelContacto               matlab.ui.control.Label
        LabelHorario                matlab.ui.control.Label
        LabelLocHorCont             matlab.ui.control.Label
        LabelLoc                    matlab.ui.control.Label
        ImgMapa                     matlab.ui.control.Image
        LabelLocalizacionContacto   matlab.ui.control.Label
        ImgTelephone                matlab.ui.control.Image
        ImgLoc                      matlab.ui.control.Image
        LabelQuienesSomos2          matlab.ui.control.Label
        LabelQuienesSomos           matlab.ui.control.Label
        ImgPerson                   matlab.ui.control.Image
        HTMLRectInstr4              matlab.ui.control.HTML
        LabelPaginaWeb4             matlab.ui.control.Label
        ImgPaginaWeb4               matlab.ui.control.Image
        LabelCerrarApp4             matlab.ui.control.Label
        ImgCandado4                 matlab.ui.control.Image
        HTMLFechaHora4              matlab.ui.control.HTML
        LabelID4                    matlab.ui.control.Label
        LabelDoctor4                matlab.ui.control.Label
        ImgDoctor4                  matlab.ui.control.Image
        LabelAcercaDe4              matlab.ui.control.Label
        ButtonAcercaDe4             matlab.ui.control.Button
        LabelInforme4               matlab.ui.control.Label
        ButtonInforme4              matlab.ui.control.Button
        LabelGlaucoma4              matlab.ui.control.Label
        ButtonGlaucoma4             matlab.ui.control.Button
        LabelInstr4                 matlab.ui.control.Label
        ButtonInstr4                matlab.ui.control.Button
        LabelLogIn4                 matlab.ui.control.Label
        ButtonLogIn4                matlab.ui.control.Button
        ImgLogo5                    matlab.ui.control.Image
        LabelBordeInferior5         matlab.ui.control.Label
        LabelInferior5              matlab.ui.control.Label
        LabelBordeSuperior5         matlab.ui.control.Label
        LabelSuperior5              matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Módulo 1, Inicio de Sesión:
        % Tabla de datos de doctores.
        doctores 
        % Sonido de correcto.
        s1 
        Fs1
        % Sonido de error.
        s2 
        Fs2
        % Sonido de botón.
        s3 
        Fs3

        % Módulo 2, Cribado de Calidad:
        % Sonido de cerrar sesión.
        s4
        Fs4
        QualityModel % Modelo de cribado de calidad.
        quality % Celda de codificación de calidad de las imágenes.
        Repositorios % Rutas de los repositorios de imágenes
        imgExtensions % Tipos de extensiones de imágenes.
        ImgActual % Variable para guardar la imagen actual.
        calidad
        
        meanHistRGB
        histogram_capilares
        
        SinCaps
        capilaresResaltados
        ROI_SinCaps
        ROI_double
        ROI
        OD
        maskOD
        centroides_originales
        ancho_original

        GlaucomaModel
        glaucoma
        probabilidades
    end
    
    methods (Access = private)
         % Función para el cálculo automático de métricas de calidad.
         function [y1, y2, y3, y4, y5, y6] = CalcularMetricasCalidad(~,x)
             x = rgb2gray(x); % Convertir imágen a escala de grises.

             % Parametros para cuantificar contraste:
             y1 = entropy(x); % Calcular la entropia de la imagen.
             y2 = max((x(:))) - min((x(:))); % Calcular el rango dinámico de la imagen.
             y3 = std2(double(x(:))); % Calcular el contraste de la imagen
            
             % Parametros para cuantificar el desenfoque y el ruido:
             laplacian = imfilter(double(x), fspecial('laplacian')); % Calcular la laplaciana de la imagen.
             y4 = max(laplacian(:)); % Calcular el máximo de la laplaciana.
             y5 = mean(laplacian(:)); % Calcular la media de la laplaciana.
             y6 = std(laplacian(:)); % Calcular la desviación estándar de la laplaciana.
         end
        
        function [area, entropiaROIG, ratio95_Ig, media, energia_total_cD, mean_cD] = featuresfinal(~, OD, ROI, ODmask, ancho_original)
            
            G=double(OD); 
            %area_Resize3000 = nnz(G);
            factor_resizing=ancho_original/3000;
            ODmask_originalSize = imresize(ODmask, [NaN, size(ODmask,2)*factor_resizing], 'bicubic');
            area=sum(ODmask_originalSize(:));
            [L,N] = superpixels(G,700, "NumIterations",50,"Compactness",10000);
            outputImage = zeros(size(G),'like',G);
            idx = label2idx(L);
            for labelVal = 1:N
                redIdx = idx{labelVal};
                outputImage(redIdx) = mean(G(redIdx));
            end
            max95=outputImage>max(outputImage(:)).*0.95;
            ratio95_Ig = nnz(max95)/nnz(outputImage);
            
            G_ROI=double(ROI(:,:,2));
            entropiaROIG=entropy(G_ROI);
        
            B_OD_segmented=OD(:,:,3);%canal azul
            
            
            Suma = sum(B_OD_segmented, 'all');
            NotZeros = nnz(ODmask);
            media = Suma / NotZeros; % Media de intensidad superior
                
                 
                %las propdiedades wavelet:
                LAB=rgb2lab(OD);
                L=LAB(:,:,1);
                [~,~,~,cD_db3] = dwt2(L,'db3');
                [~,~,~,cD_sym3] = dwt2(L,'sym3');
                [~,~,~,cD_haar] = dwt2(L,'haar');
                [~,~,~,cD_bior3] = dwt2(L,'bior3.3');
                [~,~,~,cD_bior5] = dwt2(L,'bior3.5');
                [~,~,~,cD_bior7] = dwt2(L,'bior3.7');
                meancD_db3 = mean(cD_db3(:));
                meancD_sym3 = mean(cD_sym3(:));
                meancD_haar = mean(cD_haar(:));
                meancD_bior3 = mean(cD_bior3(:));
                meancD_bior5 = mean(cD_bior5(:));
                meancD_bior7 = mean(cD_bior7(:));
                % Calcula la media de todas las medias de los coeficientes diagonales
                mean_cD = mean([meancD_db3, meancD_sym3, meancD_haar, meancD_bior3, meancD_bior5, meancD_bior7]);
                % Calcula la energía de los coeficientes de detalle diagonal
                energia_cD_db3 = sum(cD_db3(:).^2);
                energia_cD_sym3 = sum(cD_sym3(:).^2);
                energia_cD_haar = sum(cD_haar(:).^2);
                energia_cD_bior3 = sum(cD_bior3(:).^2);
                energia_cD_bior5 = sum(cD_bior5(:).^2);
                energia_cD_bior7 = sum(cD_bior7(:).^2);
                % Calcula la energía media de todas las energías de los coeficientes diagonales
                energia_total_cD = mean([energia_cD_db3, energia_cD_sym3, energia_cD_haar, energia_cD_bior3, energia_cD_bior5, energia_cD_bior7]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load the .mat file into a structure
            data = load('meanHistRGB9_dobles.mat');
            
            % Assign the specific variable to the app property
            app.meanHistRGB = data.meanHistRGB;

            % Load the .mat file into a structure
            data = load('histogram_capilares.mat');
            
            % Assign the specific variable to the app property
            app.histogram_capilares = data.histogram_capilares;
            
            % Módulo 1, Inicio de Sesión:

            % Cargar sonidos:
            correcto = load(fullfile('Sonidos', 'Correcto.mat'));
            app.s1 = correcto.y;
            app.Fs1 = correcto.Fs;

            error = load(fullfile('Sonidos', 'error.mat'));
            app.s2 = error.y;
            app.Fs2 = error.Fs;

            boton = load(fullfile('Sonidos', 'Boton.mat'));
            app.s3 = boton.y;
            app.Fs3 = boton.Fs;

            % Cargar datos de doctores:
            app.doctores = readtable(fullfile('Doctores', 'Doctores.csv')); % Cargar la tabla de metadata.
            
            % Módulo 3, Detección de Glaucoma:

            app.ButtonTrash.Icon = fullfile('Imagenes','trash.png');

            % Cargar imágenes:
            app.imgExtensions = {'.jpg', '.jpeg', '.png', '.gif', '.bmp', '.tiff'}; % Posibles extensiones de imágenes.
            app.ImgRFI.Tooltip = 'Seleccione una imagen de fondo de ojo por favor.';

            % Cargar HTML:
            app.HTMLFechaHora2.HTMLSource = fullfile('html', 'fecha_hora.html');

            % Cargar sonidos:
            cerrarSesion = load(fullfile('Sonidos', 'CerrarSesion.mat'));
            app.s4 = cerrarSesion.y;
            app.Fs4 = cerrarSesion.fs;

            % Cargar el modelo de cribado de calidad:
            load(fullfile('Modelos', 'trainedClassifierSVM.mat'));
            app.QualityModel = trainedClassifierSVM;

            % Cargar el modelo de diagnóstico del glaucoma:
            load(fullfile('Modelos', 'Glaucoma.mat'));
            app.GlaucomaModel = modelo_SVN
            disp(modelo_SVN.RequiredVariables)

            % Definir la codificación de imagenes no revidadas, de bajo contraste, con desenfoque, con ruido y de calidad correcta.
            app.quality = { 
                    0,'Imagen no revisada';
                    1,'Bajo contraste';
                    2,'Desenfoque';
                    3,'Ruido';
                    4,'Calidad correcta'
                    };

            app.glaucoma = { 
                    0,'Sano';
                    1,'Glaucoma';
                    }; 

            % Inicializar la celda para guardar los repositorios:
            app.Repositorios = {};
            
            % Esconder el aviso de carga de repositorio:
            app.ImgCarga.Visible = 'off';
            app.EditFieldCarga.Visible = 'off';

            % Selector de canales:
            
            addStyle(app.DropDownElegirCanal,uistyle("BackgroundColor","#FFFFFF","FontColor","#000000"),"item",1);
            addStyle(app.DropDownElegirCanal,uistyle("BackgroundColor","#808080"),"item",2);
            addStyle(app.DropDownElegirCanal,uistyle("BackgroundColor","#FF0000"),"item",3);
            addStyle(app.DropDownElegirCanal,uistyle("BackgroundColor","#00FF00"),"item",4);
            addStyle(app.DropDownElegirCanal,uistyle("BackgroundColor","#0000FF"),"item",5);

            addStyle(app.NodeRepositorio.Parent, uistyle("FontWeight", "bold"));

            % Módulo 4, Crear Informe:

            app.HTMLRectInforme.HTMLSource = fullfile('html','rect2.html');
        end

        % Callback function
        function ImgCandadoImageClicked(app, event)

        end

        % Callback function
        function DeterminarcalidadButtonPushed(app, event)

        end

        % Button pushed function: ButtonAnadirRepositorio
        function ButtonAnadirRepositorioPushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.GlauDTect.Pointer = 'watch'; % Poner el cursor en modo espera.
            % Enseñar el aviso de carga de repositorio:
            app.ImgCarga.Visible = 'on';
            app.EditFieldCarga.Visible = 'on';
            app.ButtonAnadirImagen.Visible = 'off';

            selectedFolder = uigetdir; % Selecionar repositorio de imágenes.
            app.Repositorios = [app.Repositorios, {selectedFolder}]; % Guardar el repositorio actual en la celda.
            [~, folderName, ~] = fileparts(selectedFolder); % Obtener el nombre de la carpeta seleccionada.
            files = dir(selectedFolder); 
            fileNames = {files(~[files.isdir]).name}; % Obtener la lista de imágenes del repositorio.

            parent = uitreenode(app.NodeRepositorio, "Text", folderName); % Crear un nodo con el nombre del repositorio.
            parent.Icon = fullfile('Imagenes', 'carpeta2.png'); % Añadir el icono de carpeta al lado del nodo del repositorio.

            for i = 1:length(fileNames)
                child = uitreenode(parent, "Text", fileNames{1,i}); % Colocar todas las imágenes del repositorio en el árbol.
                child.Icon = fullfile(selectedFolder, fileNames{1,i}); % Añadir el icono de las imágenes.
            end
            
            app.GlauDTect.Pointer = 'arrow'; % Poner el cursor en modo por defecto.
            % Esconder el aviso de carga de repositorio:
            app.ImgCarga.Visible = 'off';
            app.EditFieldCarga.Visible = 'off';
            app.ButtonAnadirImagen.Visible = 'on';
        end

        % Image clicked function: ImgCandado2
        function ImgCandado2ImageClicked2(app, event)
            sound(app.s4, app.Fs4) % Reproducir sonido de cerrar sesión.
            pause(0.5); % Esperar medio segundo.
            delete(app); % Cerrar la aplicación.
        end

        % Selection changed function: TreeRepositorio
        function TreeRepositorioSelectionChanged(app, event)
            selectedNode = app.TreeRepositorio.SelectedNodes; % Seleccionar imagen de un repositorio.

            if isempty(selectedNode.Children) && ~isempty(selectedNode.Parent)
                parentNode = selectedNode.Parent.Text;
                [~, folderNames, ~] = fileparts(app.Repositorios);
                idx = find(strcmp(folderNames, parentNode));
                
                app.DropDownElegirCanal.Value = "Color";
                app.Lamp.Color = [1 1 1];
                app.ImgRFI.ImageSource = imread(fullfile(app.Repositorios{idx}, selectedNode.Text));
                app.ImgActual = app.ImgRFI.ImageSource;
                app.EditFieldImagen.Text = selectedNode.Text;

                img = app.ImgActual;
                app.ImgRFI.ImageSource = img;

                img_red = img(:,:,1); % Convertir imagen roja a escala de grises.
                img_red = cat(3, img_red, img_red, img_red); % Convertir imagen roja a formato RGB.
                img_green = img(:,:,2); % Convertir imagen verde a escala de grises.
                img_green = cat(3, img_green, img_green, img_green); % Convertir imagen verde a formato RGB.
                img_blue = img(:,:,3); % Convertir imagen azul a escala de grises.
                img_blue = cat(3, img_blue, img_blue, img_blue); % Convertir imagen azul a formato RGB.
                
                cla(app.Histogram); % Limpiar histograma actual.
                [histograma, intensidades] = imhist(img_red); % Calcular el histograma verde.
                bar(app.Histogram, intensidades, histograma, 'FaceColor', [1 0 0]); % Dibujar el histograma rojo.
                xticks(app.Histogram, [0:25:225, 255]);
                hold(app.Histogram, 'on');

                [histograma, intensidades] = imhist(img_green); % Calcular el histograma verde.
                bar(app.Histogram, intensidades, histograma, 'FaceColor', [0 1 0]); % Dibujar el histograma verde.
                hold(app.Histogram, 'on');

                [histograma, intensidades] = imhist(img_blue); % Calcular el histograma azul.
                bar(app.Histogram, intensidades, histograma, 'FaceColor', [0 0 1]); % Dibujar el histograma azul.
            end
        end

        % Callback function
        function DeterminarcalidadButtonPushed2(app, event)

        end

        % Button pushed function: ButtonLogIn2
        function ButtonLogIn2Pushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(1); % Ir al módulo de cribado de calidad.
            
            % Limpiar todos los campos de inicio de sesión:
            app.EditFieldNombreApellidos.Value = '';
            app.EditFieldID.Value = '';
            app.EditFieldCorreo.Value = '';
            app.EditFieldContrasena.Value = '';
            
            % Esconder el campo de inicio de sesión erróneo:
            app.ImgError.Visible = 'off';
            app.EditFieldError.Visible = 'off';
            
            % Esconder el campo de inicio de sesión correcto:
            app.ImgCorrecto.Visible = 'off';
            app.EditFieldCorrecto.Visible = 'off';
        end

        % Button pushed function: ButtonInstr2
        function ButtonInstr2Pushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(2); % Ir al módulo de cribado de calidad.
        end

        % Button pushed function: ButtonGlaucoma2
        function ButtonGlaucoma2Pushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(3); % Ir al módulo de detección de glaucoma.
        end

        % Button pushed function: ButtonInforme2
        function ButtonInforme2Pushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(4); % Ir al módulo de creación de informe.
        end

        % Button pushed function: ButtonAcercaDe2
        function ButtonAcercaDe2Pushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(5); % Ir al módulo de acerca de.
        end

        % Button pushed function: ButtonInicioSesion
        function ButtonInicioSesionPushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            for i = 1:height(app.doctores)
                if strcmp(char(app.doctores{i,1}), app.EditFieldNombreApellidos.Value) && strcmp(char(app.doctores{i,2}), app.EditFieldCorreo.Value) && strcmp(char(app.doctores{i,3}), app.EditFieldID.Value) && strcmp(char(app.doctores{i,4}), app.EditFieldContrasena.Value)
                    sound(app.s1, app.Fs1) % Reproducir sonido de correcto.

                    % Esconder el campo de inicio de sesión erróneo:
                    app.ImgError.Visible = 'off';
                    app.EditFieldError.Visible = 'off';
                    
                    % Enseñar el campo de inicio de sesión correcto:
                    app.ImgCorrecto.Visible = 'on';
                    app.EditFieldCorrecto.Visible = 'on';
                    
                    app.ImgDoctor2.ImageSource = imread(fullfile('Imagenes', [app.EditFieldNombreApellidos.Value, '.png']));
                    app.ImgDoctor3.ImageSource = imread(fullfile('Imagenes', [app.EditFieldNombreApellidos.Value, '.png']));
                    app.ImgDoctor4.ImageSource = imread(fullfile('Imagenes', [app.EditFieldNombreApellidos.Value, '.png']));
                    app.ImgDoctor.ImageSource = imread(fullfile('Imagenes', [app.EditFieldNombreApellidos.Value, '.png']));

                    pause(0.5); % Esperar medio segundo.
                    app.Modulos.SelectedTab = app.Modulos.Children(2); % Ir al módulo de instrucciones.
                    break;
                elseif isempty(app.EditFieldNombreApellidos.Value) && isempty(app.EditFieldCorreo.Value) && isempty(app.EditFieldID.Value) && isempty(app.EditFieldContrasena.Value)
                    uialert(app.GlauDTect,'¡Por favor rellene todos los campos!', 'Campos incompletos');
                else
                    sound(app.s2, app.Fs2) % Reproducir sonido de error.

                    % Enseñar el campo de inicio de sesión erróneo:
                    app.ImgError.Visible = 'on';
                    app.EditFieldError.Visible = 'on';
                    
                    % Esconder el campo de inicio de sesión correcto:
                    app.ImgCorrecto.Visible = 'off';
                    app.EditFieldCorrecto.Visible = 'off';
                end
            end
            app.ImgDoctor2.Tooltip = ['Dr. ', app.EditFieldNombreApellidos.Value];
            app.LabelDoctor2.Text = ['Dr. ', app.EditFieldNombreApellidos.Value];
            app.LabelID2.Text = ['ID: ', app.EditFieldID.Value];

            app.ImgDoctor3.Tooltip = ['Dr. ', app.EditFieldNombreApellidos.Value];
            app.LabelDoctor3.Text = ['Dr. ', app.EditFieldNombreApellidos.Value];
            app.LabelID3.Text = ['ID: ', app.EditFieldID.Value];

            app.ImgDoctor4.Tooltip = ['Dr. ', app.EditFieldNombreApellidos.Value];
            app.LabelDoctor4.Text = ['Dr. ', app.EditFieldNombreApellidos.Value];
            app.LabelID4.Text = ['ID: ', app.EditFieldID.Value];

            app.ImgDoctor.Tooltip = ['Dr. ', app.EditFieldNombreApellidos.Value];
            app.LabelDoctor.Text = ['Dr. ', app.EditFieldNombreApellidos.Value];
            app.LabelID.Text = ['ID: ', app.EditFieldID.Value];
        end

        % Value changing function: EditFieldNombreApellidos
        function EditFieldNombreApellidosValueChanging(app, event)
            changingValue = event.Value;
            if ~isempty(changingValue)
                app.Ast1.Visible = 'off';
            else
                app.Ast1.Visible = 'on';
            end
        end

        % Value changing function: EditFieldCorreo
        function EditFieldCorreoValueChanging(app, event)

        end

        % Value changing function: EditFieldContrasena
        function EditFieldContrasenaValueChanging(app, event)
            changingValue = event.Value;
            if ~isempty(changingValue)
                app.Ast3.Visible = 'off';
            else
                app.Ast3.Visible = 'on';
            end
        end

        % Button pushed function: ButtonLimpiar
        function ButtonLimpiarButtonPushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            
            % Limpiar todos los cmpos de inicio de sesión:
            app.EditFieldNombreApellidos.Value = '';
            app.EditFieldID.Value = '';
            app.EditFieldCorreo.Value = '';
            app.EditFieldContrasena.Value = '';
            
            % Enseñar los asteriscos de campo obligatorio:
            app.Ast1.Visible = 'on';
            app.Ast2.Visible = 'on';
            app.Ast3.Visible = 'on';
            
            % Esconder el campo de inicio de sesión erróneo:
            app.ImgError.Visible = 'off';
            app.EditFieldError.Visible = 'off';

            % Esconder el campo de inicio de sesión correcto:
            app.ImgCorrecto.Visible = 'off';
            app.EditFieldCorrecto.Visible = 'off';
        end

        % Value changing function: EditFieldID
        function EditFieldIDValueChanging(app, event)
            changingValue = event.Value;
            if ~isempty(changingValue)
                app.Ast2.Visible = 'off';
            else
                app.Ast2.Visible = 'on';
            end
        end

        % Button pushed function: ButtonDeterminarCalidad
        function ButtonDeterminarCalidadPushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón. 
            if ~isempty(app.ImgRFI.ImageSource)
                app.GlauDTect.Pointer = 'watch'; % Poner el cursor en modo espera.
                
                % Llamar a la función para calcular las métricas de calidad de la imagen:
                [y1, y2, y3, y4, y5, y6] = CalcularMetricasCalidad(app, app.ImgActual); % Calcular las métricas de calidad.
                data = {y1, y2, y3, y4, y5, y6};
                T = cell2table(data, 'VariableNames', {'entropy', 'dynamic_range', 'contrast', 'lap_max', 'lap_mean', 'lap_std'});
                app.EntropiaEditField.Value = num2str(round(double(y1), 2));
                app.RangoDinamicoEditField.Value = num2str(round(double(y2), 2));
                app.ContrasteEditField.Value = num2str(round(double(y3), 2));
                app.LapMaxEditField.Value = num2str(round(double(y4), 2));
                app.LapMedEditField.Value = num2str(round(double(y5), 4));
                app.LapDesEditField.Value = num2str(round(double(y6), 2));
                
                ypred = double(app.QualityModel.predictFcn(T));
                app.LabelCalidad.Text = app.quality{cellfun(@(x) x == ypred, app.quality(:,1)), 2};

                if app.LabelCalidad.Text == "Calidad correcta"
                    sound(app.s1,app.Fs1)
                    app.LampCalidad.Color = [0 1 0];
                else
                    sound(app.s2,app.Fs2)
                    app.LampCalidad.Color = [1 0 0];
                end
                
                app.GlauDTect.Pointer = 'arrow'; % Poner el cursor en modo por defecto.

                disp(app.LabelCalidad.Text)
            end
        end

        % Value changed function: DropDownElegirCanal
        function DropDownElegirCanalValueChanged(app, event)
            value = app.DropDownElegirCanal.Value; % Guardar el canal elegido.
            if value == "Color"
                app.Lamp.Color = [1 1 1]; % Iluminar la lámpara en blanco.
                if ~isempty(app.ImgRFI.ImageSource)
                    cla(app.Histogram); % Limpiar el histograma actual.

                    img = app.ImgActual; % Obtener la imagen actual.
                    app.ImgRFI.ImageSource = img; % Visualizar la imagen actual.

                    img_red = img(:,:,1); % Convertir imagen roja a escala de grises.
                    img_red = cat(3, img_red, img_red, img_red); % Convertir imagen roja a formato RGB.
                    img_green = img(:,:,2); % Convertir imagen verde a escala de grises.
                    img_green = cat(3, img_green, img_green, img_green); % Convertir imagen verde a formato RGB.
                    img_blue = img(:,:,3); % Convertir imagen azul a escala de grises.
                    img_blue = cat(3, img_blue, img_blue, img_blue); % Convertir imagen azul a formato RGB.

                    [histograma, intensidades] = imhist(img_red); % Calcular el histograma rojo.
                    bar(app.Histogram, intensidades, histograma, 'FaceColor', [1 0 0]); % Dibujar el histograma rojo.
                    hold(app.Histogram, 'on');
                    xticks(app.Histogram, [0:25:225, 255]);

                    [histograma, intensidades] = imhist(img_green); % Calcular el histograma verde.
                    bar(app.Histogram, intensidades, histograma, 'FaceColor', [0 1 0]); % Dibujar el histograma verde.
                    hold(app.Histogram, 'on');

                    [histograma, intensidades] = imhist(img_blue); % Calcular el histograma azul.
                    bar(app.Histogram, intensidades, histograma, 'FaceColor', [0 0 1]); % Dibujar el histograma azul.
                end
            elseif value == "Gris"
                app.Lamp.Color = [0.5 0.5 0.5]; % Iluminar la lámpara en gris.
                if ~isempty(app.ImgRFI.ImageSource)
                    cla(app.Histogram); % Limpiar el histograma actual.
                    img = app.ImgActual;
                    img_gray = im2gray(img); % Convierte a escala de grises
                    img_rgb = cat(3, img_gray, img_gray, img_gray); % Convierte a formato RGB
                    app.ImgRFI.ImageSource = img_rgb;
                    % Calcula el histograma
                    [histograma, intensidades] = imhist(im2gray(app.ImgRFI.ImageSource));
                    % Dibuja el histograma en el UIAxes
                    bar(app.Histogram, intensidades, histograma, 'FaceColor', [0.5 0.5 0.5]);
                    xticks(app.Histogram, [0:25:225, 255]);
                end
            elseif value == "Rojo"
                app.Lamp.Color = [1 0 0]; % Iluminar la lámpara en rojo.
                if ~isempty(app.ImgRFI.ImageSource)
                    cla(app.Histogram); % Limpiar el histograma actual.

                    img = app.ImgActual; % Obtener la imagen actual.
                    img_red = img(:,:,1); % Obtener el canal rojo.
                    img_red = cat(3, img_red, img_red, img_red); % Convierte a formato RGB.
                    app.ImgRFI.ImageSource = img_red; % Visualizar el canal rojo.

                    [histograma, intensidades] = imhist(img_red); % Calcular el histograma rojo.
                    bar(app.Histogram, intensidades, histograma, 'FaceColor', [1 0 0]); % Dibujar el histograma rojo.
                    xticks(app.Histogram, [0:25:225, 255]);
                end
            elseif value == "Verde"
                app.Lamp.Color = [0 1 0]; % Iluminar la lámpara en verde.
                if ~isempty(app.ImgRFI.ImageSource)
                    cla(app.Histogram); % Limpiar el histograma actual.

                    img = app.ImgActual; % Obtener la imagen actual.
                    img_green = img(:,:,2); % Obtener el canal verde
                    img_green = cat(3, img_green, img_green, img_green); % Convierte a formato RGB.
                    app.ImgRFI.ImageSource = img_green; % Visualizar el canal verde.

                    [histograma, intensidades] = imhist(img_green); % Calcular el histograma verde.
                    bar(app.Histogram, intensidades, histograma, 'FaceColor', [0 1 0]); % Dibujar el histograma verde.
                    xticks(app.Histogram, [0:25:225, 255]);
                end
            elseif value == "Azul"
                app.Lamp.Color = [0 0 1]; % Iluminar la lámpara en azul.
                if ~isempty(app.ImgRFI.ImageSource)
                    cla(app.Histogram); % Limpiar el histograma actual.

                    img = app.ImgActual; % Obtener la imagen actual.
                    img_blue = img(:,:,3); % Obtener el canal azul.
                    img_blue = cat(3, img_blue, img_blue, img_blue); % Convierte a formato RGB.
                    app.ImgRFI.ImageSource = img_blue; % Visualizar el canal azul

                    [histograma, intensidades] = imhist(img_blue); % Calcular el histograma azul.
                    bar(app.Histogram, intensidades, histograma, 'FaceColor', [0 0 1]); % Dibujar el histograma azul.
                    xticks(app.Histogram, [0:25:225, 255]);
                end
            end
        end

        % Button pushed function: ButtonAnadirImagen
        function ButtonAnadirImagenButtonPushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            
            % Abrir un diálogo para que el usuario elija una imagen
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.gif','All Image Files'}, 'Selecciona una imagen');
            
            % Verificar si se seleccionó una imagen
            if isequal(file,0)
                disp('No se seleccionó ninguna imagen.');
                return;
            end
            
            % Leer la imagen seleccionada
            imagePath = fullfile(path, file);
            
            % Cambiar la imagen del componente de imagen
            app.ImgRFI.ImageSource = imread(imagePath); % Cambia la imagen en el componente
            app.ImgActual = app.ImgRFI.ImageSource;
            img = app.ImgActual;
            % Extract the filename
            [~, filename, ~] = fileparts(imagePath);
            app.EditFieldImagen.Text = filename;

            app.DropDownElegirCanal.Value = "Color";
            app.Lamp.Color = [1 1 1];

            img_red = img(:,:,1); % Convertir imagen roja a escala de grises.
            img_red = cat(3, img_red, img_red, img_red); % Convertir imagen roja a formato RGB.
            img_green = img(:,:,2); % Convertir imagen verde a escala de grises.
            img_green = cat(3, img_green, img_green, img_green); % Convertir imagen verde a formato RGB.
            img_blue = img(:,:,3); % Convertir imagen azul a escala de grises.
            img_blue = cat(3, img_blue, img_blue, img_blue); % Convertir imagen azul a formato RGB.
                
            cla(app.Histogram); % Limpiar histograma actual.
            [histograma, intensidades] = imhist(img_red); % Calcular el histograma verde.
            bar(app.Histogram, intensidades, histograma, 'FaceColor', [1 0 0]); % Dibujar el histograma rojo.
            xticks(app.Histogram, [0:25:225, 255]);
            hold(app.Histogram, 'on');

            [histograma, intensidades] = imhist(img_green); % Calcular el histograma verde.
            bar(app.Histogram, intensidades, histograma, 'FaceColor', [0 1 0]); % Dibujar el histograma verde.
            hold(app.Histogram, 'on');

            [histograma, intensidades] = imhist(img_blue); % Calcular el histograma azul.
            bar(app.Histogram, intensidades, histograma, 'FaceColor', [0 0 1]); % Dibujar el histograma azul.
        end

        % Callback function
        function ButtonAcercaDe1Pushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(5); % Ir al módulo de acerca de.
        end

        % Button pushed function: ButtonLogIn3
        function ButtonLogIn3Pushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(1); % Ir al módulo de acerca de.
        end

        % Button pushed function: ButtonInstr3
        function ButtonInstr3Pushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(2); % Ir al módulo de acerca de.           
        end

        % Button pushed function: ButtonGlaucoma3
        function ButtonGlaucoma3Pushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(3); % Ir al módulo de acerca de.      
        end

        % Button pushed function: ButtonInforme3
        function ButtonInforme3Pushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(4); % Ir al módulo de acerca de.
        end

        % Button pushed function: ButtonAcercaDe3
        function ButtonAcercaDe3Pushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(5); % Ir al módulo de acerca de.    
        end

        % Image clicked function: ImgDoctor3
        function ImgDoctor3ImageClicked(app, event)
            
        end

        % Image clicked function: ImgDoctor2
        function ImgDoctor2ImageClicked(app, event)

        end

        % Image clicked function: ImgCandado3
        function ImgCandado3ImageClicked(app, event)
            sound(app.s4, app.Fs4) % Reproducir sonido de cerrar sesión.
            pause(0.5); % Esperar medio segundo.
            delete(app); % Cerrar la aplicación.
        end

        % Button pushed function: ButtonLogIn
        function ButtonLogInPushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(1); % Ir al módulo de acerca de. 
        end

        % Button pushed function: ButtonInstr
        function ButtonInstrPushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(2); % Ir al módulo de acerca de.
        end

        % Button pushed function: ButtonGlaucoma
        function ButtonGlaucomaPushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(3); % Ir al módulo de acerca de.
        end

        % Button pushed function: ButtonInforme
        function ButtonInformePushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(4); % Ir al módulo de acerca de.
        end

        % Button pushed function: ButtonAcercaDe
        function ButtonAcercaDePushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(5); % Ir al módulo de acerca de.
        end

        % Button pushed function: ButtonLogIn4
        function ButtonLogIn4Pushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(1); % Ir al módulo de acerca de.
        end

        % Button pushed function: ButtonInstr4
        function ButtonInstr4Pushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(2); % Ir al módulo de acerca de.
        end

        % Button pushed function: ButtonGlaucoma4
        function ButtonGlaucoma4Pushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(3); % Ir al módulo de acerca de.
        end

        % Button pushed function: ButtonInforme4
        function ButtonInforme4Pushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(4); % Ir al módulo de acerca de.
        end

        % Button pushed function: ButtonAcercaDe4
        function ButtonAcercaDe4Pushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(5); % Ir al módulo de acerca de.
        end

        % Image clicked function: ImgCandado
        function ImgCandadoImageClicked2(app, event)
            sound(app.s4, app.Fs4) % Reproducir sonido de cerrar sesión.
            pause(0.5); % Esperar medio segundo.
            delete(app); % Cerrar la aplicación.
        end

        % Image clicked function: ImgCandado4
        function ImgCandado4ImageClicked(app, event)
            sound(app.s4, app.Fs4) % Reproducir sonido de cerrar sesión.
            pause(0.5); % Esperar medio segundo.
            delete(app); % Cerrar la aplicación.
        end

        % Button pushed function: ButtonTrash
        function ButtonTrashPushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.LampCalidad.Color = [1 1 1];
            app.LampDiagnostico.Color = [1 1 1];
            app.LabelCalidad.Text = '';
            app.LapMaxEditField.Value = '';
            app.LapMedEditField.Value = '';
            app.LapDesEditField.Value = '';
            app.ContrasteEditField.Value = '';
            app.RangoDinamicoEditField.Value = '';
            app.EntropiaEditField.Value = '';
            app.LabelDiagnostico.Text = '';
            app.LabelProbabilidad.Text = '';
        end

        % Button pushed function: ButtonIniciarInforme
        function ButtonIniciarInformeButtonPushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.ImagenEditField.Value = app.EditFieldImagen.Text;
            app.EditFieldEspecialista.Value = app.EditFieldNombreApellidos.Value;
        end

        % Button pushed function: ButtonGuardarInforme
        function ButtonGuardarInformeButtonPushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            T = table({app.EditFieldImagen.Text}, {app.EditFieldNombreApellidos.Value}, {app.DatePickerFecha.Value}, {app.LabelCalidad.Text},{app.EntropiaEditField.Value}, {app.RangoDinamicoEditField.Value}, {app.ContrasteEditField.Value}, {app.LapMaxEditField.Value}, {app.LapMedEditField.Value}, {app.LapDesEditField.Value}, {app.TextAreaComentarios.Value},  ...
                'VariableNames', {'Nombre de Imagen', 'Especialista', 'Fecha', 'Calidad' 'Entropia', 'Rango dinamico', 'Contraste', 'Maximo de laplaciano', 'Media de laplaciano', 'Desviacion estandar de laplaciano', 'Comentarios adicionales'});
            % Abrir un explorador de archivos para que el usuario seleccione un directorio
            selectedPath = uigetdir;
            
            % Guardar la tabla en el directorio seleccionado
            writetable(T, fullfile(selectedPath, 'Informe.csv'));
            imwrite(app.ImgActual, fullfile(selectedPath, 'RFI.png'))
            imwrite(app.SinCaps, fullfile(selectedPath, 'RFI_No_Caps.png'))
            imwrite(app.ROI, fullfile(selectedPath, 'ROI.png'))
            imwrite(app.ROI_SinCaps, fullfile(selectedPath, 'ROI_No_Caps.png'))
            imwrite(app.capilaresResaltados, fullfile(selectedPath, 'Capilares.png'))
            imwrite(app.ROI_double, fullfile(selectedPath, 'OD_Destacado.png'))

            % Create a new figure for the subplot
            figure;
            set(gca, 'TickLabelInterpreter', 'none');
            
            % Create a 2x3 subplot and display images
            subplot(2, 3, 1);
            imshow(app.ImgActual);
            title(app.EditFieldImagen.Text, 'Interpreter', 'none');

            subplot(2, 3, 2);
            imshow(app.SinCaps);
            title('Imagen sin vasculatura');

            subplot(2, 3, 3);
            imshow(app.capilaresResaltados);
            title('Vasculatura');

            subplot(2, 3, 4);
            imshow(app.ROI);
            title('ROI');

            subplot(2, 3, 5);
            imshow(app.ROI_SinCaps);
            title('ROI sin vasculatura');

            subplot(2, 3, 6);
            imshow(app.ROI_double);
            title('Disco óptico');

            % Save the figure as a PNG file
            saveas(gcf, fullfile(selectedPath,'Informe0.png'));

            % app.ImgActual app.SinCaps app.ROI , app.ROI_SinCaps, app.capilaresDestacados, app.ROI_double
        end

        % Button pushed function: ButtonMasInfo
        function ButtonMasInfoButtonPushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            system(['start chrome "', fullfile(pwd, fullfile('GLAUCO_DTECT_WEB', 'Instrucciones de uso GLAUCO DTECT.pdf')), '"']);
            system(['start chrome "', fullfile(pwd, 'ETIQUETA V4.png'), '"']);
        end

        % Image clicked function: ImgPaginaWeb4
        function ImgPaginaWeb4ImageClicked(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            system(['start chrome "', fullfile(pwd, fullfile('GLAUCO_DTECT_WEB', 'about.html')), '"']);
        end

        % Image clicked function: ImgPaginaWeb
        function ImgPaginaWebImageClicked(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            system(['start chrome "', fullfile(pwd, fullfile('GLAUCO_DTECT_WEB', 'about.html')), '"']);
        end

        % Image clicked function: ImgPaginaWeb3
        function ImgPaginaWeb3ImageClicked(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            system(['start chrome "', fullfile(pwd, fullfile('GLAUCO_DTECT_WEB', 'about.html')), '"']);
        end

        % Image clicked function: ImgPaginaWeb2
        function ImgPaginaWeb2ImageClicked(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            system(['start chrome "', fullfile(pwd, fullfile('GLAUCO_DTECT_WEB', 'about.html')), '"']);
        end

        % Callback function: HyperlinkInstrucciones
        function HyperlinkInstruccionesClicked(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            system(['start chrome "', fullfile(pwd, fullfile('GLAUCO_DTECT_WEB', 'Instrucciones de uso GLAUCO DTECT.pdf')), '"']);
        end

        % Button pushed function: ButtonSegmentar
        function ButtonSegmentarPushed(app, event)
            % app.ImgActual app.SinCaps app.ROI , app.ROI_SinCaps, app.capilaresDestacados, app.ROI_double

            function myprogress
                  d = uiprogressdlg(app.GlauDTect, ...
                                    'Title', 'Segmentando disco óptico', ...
                                    'Message', 'Por favor espere...', ...
                                    'Icon', fullfile('Imagenes','ojo.png'), ...
                                    'Indeterminate', 'on');
                  drawnow
                  RGB = app.ImgRFI.ImageSource;
                  [RGB, alto_original, app.ancho_original] = OD_Localization_Preprocessing(RGB);
                    
                  R = RGB(:,:,1); 
                  Tr = graythresh(R);
                  IrT = imbinarize(R, Tr);
                  IrTE = edge(IrT, 'Sobel');
                  IrTE = imdilate(IrTE,strel('disk',30));
                    
                  umbralBrightnessG=0.86;
                  [IgT_cleaned, cc2]=G_layer_BrightestRegion_HistogramTemplateMatching(RGB,umbralBrightnessG,app.meanHistRGB,IrTE);
                    
                    
                  if cc2.NumObjects==0
                     umbralBrightnessG=0.60;%Decidido mediante experimentación
                     [IgT_cleaned,~]=G_layer_BrightestRegion_HistogramTemplateMatching(RGB,umbralBrightnessG,app.meanHistRGB,IrTE);
                  end
                 
                  I=rgb2gray(RGB);
                  brightest_region = imoverlay(I,IgT_cleaned,'blue');
                  [app.SinCaps,mascaraCapilares]=extraerCapilaresDeFundus(RGB, app.histogram_capilares);
                    
                  modo=3;
                  [app.ROI, app.ROI_SinCaps, Validez, app.centroides_originales]=extraerROI(IgT_cleaned,modo,RGB,app.SinCaps);%modo=1 para ROI con capilares, modo=2 para ROI sin capilares, modo=3 para ambos, modo=[] para solo parámetros
                    
                  cuadrado = zeros(1001);
                  cuadrado(1:40, :) = 1; % Primeras 20 filas
                  cuadrado(end-39:end, :) = 1; % Últimas 20 filas
                  cuadrado(:, 1:40) = 1; % Primeras 20 columnas
                  cuadrado(:, end-39:end) = 1; % Últimas 20 columnas
                    
                  [app.OD, app.maskOD, bordesOD, Validez2] = ODsegmentation(app.ROI_SinCaps, cuadrado);
    
                  app.ROI_double = im2double(app.ROI);
                  background_attenuation = 0.6;
                  app.ROI_double(~app.maskOD) = app.ROI_double(~app.maskOD) * background_attenuation;
                  OD_highlight_factor = 1.2;
                  app.ROI_double(app.maskOD) = app.ROI_double(app.maskOD) * OD_highlight_factor;
                  app.ROI_double = mat2gray(app.ROI_double);
                  clusters=imcomplement(mascaraCapilares);
                  app.capilaresResaltados = imoverlay(RGB,clusters,'g');
    
                  if Validez2 == 0
                      app.ImgOD.ImageSource = app.ROI_double;
                      close(d)
                  else
                      close(d)
                      uialert(app.GlauDTect, 'El disco óptico no se ha segmentado correctamente, por favor, realice la segmentación manualmente.', '¡Error de segmentación!');
                      pause(2)
                      close all
                      figure;
                      the_fig = imshow(app.ROI);
                      title('Mueva y ajuste el tamaño de la elipse para cubrir el área del disco óptico');
                      % maximize figure for better visualization
                      set(gcf, 'Position', get(0,'Screensize'));
                        
                      % mark the ellipse
                      h = imellipse;
                      wait(h);
                      % generate the binary mask
                      app.maskOD = createMask(h, the_fig);
                      % assign the mask to its real position in the image
                      app.ROI_double = im2double(app.ROI);
                      background_attenuation = 0.8;
                      app.ROI_double(~app.maskOD) = app.ROI_double(~app.maskOD) * background_attenuation;
                      OD_highlight_factor = 1.2;
                      app.ROI_double(app.maskOD) = app.ROI_double(app.maskOD) * OD_highlight_factor;
                      app.ROI_double = mat2gray(app.ROI_double);
                      app.ImgOD.ImageSource = app.ROI_double;
                      clusters=imcomplement(mascaraCapilares);
                      app.capilaresResaltados = imoverlay(RGB,clusters,'g');
                      close all;
                  end   
            end

            if app.LabelCalidad.Text == "Calidad correcta"
                sound(app.s3, app.Fs3) % Reproducir sonido de botón.
                myprogress
            else
                sound(app.s2,app.Fs2)
                uialert(app.GlauDTect, 'La imagen introducida tiene problemas de calidad, por favor introduzca otra imagen.', '¡Error de calidad!');
            end
        end

        % Value changed function: DiscopticoDropDown
        function DiscopticoDropDownValueChanged(app, event)
            value = app.DiscopticoDropDown.Value;
            
            if value == "Disco óptico"
                app.ImgOD.ImageSource = app.ROI_double;
            elseif value == "Vasculatura"
                app.ImgOD.ImageSource = app.capilaresResaltados;
            elseif value == "ROI"
                app.ImgOD.ImageSource = app.ROI;
            end
        end

        % Button pushed function: ButtonSegmentar_2
        function ButtonSegmentar_2Pushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            [area, entropiaROIG, ratio95_Ig, media, energia_total_cD, mean_cD] = featuresfinal(app, app.OD, app.ROI, app.maskOD, app.ancho_original);
            data = {area, entropiaROIG, ratio95_Ig, media, energia_total_cD, mean_cD};
            T = cell2table(data, 'VariableNames', {'Area', 'Entropia', 'Ratio', 'Media', 'Energia_cD', 'Media_cD'});

            [ypred, scores_SVN] = app.GlaucomaModel.predictFcn(T);
            ypred = double(ypred);

            app.LabelDiagnostico.Text = app.glaucoma{cellfun(@(x) x == ypred, app.glaucoma(:,1)), 2};

            if app.LabelDiagnostico.Text == "Sano"
                sound(app.s1,app.Fs1)
                app.LampDiagnostico.Color = [0 1 0];
            else
                sound(app.s2,app.Fs2)
                app.LampDiagnostico.Color = [1 0 0];
            end
            app.probabilidades = 1 ./ (1 + exp(-scores_SVN));
        end

        % Button pushed function: ButtonSegmentar_3
        function ButtonSegmentar_3Pushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            
            % Assuming app.probabilidades is a vector or array
            probabilidad = app.probabilidades(2); % Get the second element
            percentageValue = round(probabilidad * 100,2); % Multiply by 100 and round to the nearest whole number
            percentageString = strcat(num2str(percentageValue), '%'); % Convert to string and append '%'

            app.LabelProbabilidad.Text = percentageString;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GlauDTect = uifigure('Visible', 'off');
            app.GlauDTect.Name = 'MATLAB App';
            app.Modulos = uitabgroup(app.GlauDTect);
            app.IniciarSesion = uitab(app.Modulos);
            app.IniciarSesion.Title = 'IniciarSesion';
            app.ImgFondo = uiimage(app.IniciarSesion);
            app.ImgFondo.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'fondo.png');
            app.LabelSuperior = uilabel(app.IniciarSesion);
            app.LabelSuperior.Text = 'SOFTWARE DE AYUDA AL DIAGNÓSTICO TEMPRANO DEL GLAUCOMA';
            app.LabelBordeSuperior = uilabel(app.IniciarSesion);
            app.LabelBordeSuperior.Text = '0';
            app.LabelInferior = uilabel(app.IniciarSesion);
            app.LabelInferior.Text = 'Glau D-Tect de VisionHealth Systems';
            app.LabelBordeInferior = uilabel(app.IniciarSesion);
            app.LabelBordeInferior.Text = '0';
            app.HTMLCuadrado = uihtml(app.IniciarSesion);
            app.HTMLCuadrado.HTMLSource = 'html/square.html';
            app.LabelBienvenido = uilabel(app.IniciarSesion);
            app.LabelBienvenido.Text = '¡Bienvenido!';
            app.ImgLogo1 = uiimage(app.IniciarSesion);
            app.ImgLogo1.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'logo.png');
            app.LabelInicioDeSesion = uilabel(app.IniciarSesion);
            app.LabelInicioDeSesion.Text = 'Inicio de Sesión';
            app.Ast1 = uilabel(app.IniciarSesion);
            app.Ast1.Tooltip = {'¡Campo obligatorio!'};
            app.Ast1.Text = '*';
            app.EditFieldNombreApellidos = uieditfield(app.IniciarSesion, 'text');
            app.EditFieldNombreApellidos.ValueChangingFcn = createCallbackFcn(app, @EditFieldNombreApellidosValueChanging, true);
            app.EditFieldNombreApellidos.Tooltip = {'Introduzca su nombre y apellidos por favor.'};
            app.EditFieldNombreApellidos.Placeholder = 'Nombre y Apellidos';
            app.ImgNombreApellidos = uiimage(app.IniciarSesion);
            app.ImgNombreApellidos.Tooltip = {'Introduzca su nombre y apellidos por favor.'};
            app.ImgNombreApellidos.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'doctor.png');
            app.Ast2 = uilabel(app.IniciarSesion);
            app.Ast2.Tooltip = {'¡Campo obligatorio!'};
            app.Ast2.Text = '*';
            app.EditFieldID = uieditfield(app.IniciarSesion, 'text');
            app.EditFieldID.ValueChangingFcn = createCallbackFcn(app, @EditFieldIDValueChanging, true);
            app.EditFieldID.Tooltip = {'Introduzca su código ID por favor.'};
            app.EditFieldID.Placeholder = 'Código ID';
            app.EditFieldCorreo = uieditfield(app.IniciarSesion, 'text');
            app.EditFieldCorreo.ValueChangingFcn = createCallbackFcn(app, @EditFieldCorreoValueChanging, true);
            app.EditFieldCorreo.Tooltip = {'Introduzca su correo electrónico por favor.'};
            app.EditFieldCorreo.Placeholder = 'Correo Electrónico';
            app.ImgID = uiimage(app.IniciarSesion);
            app.ImgID.Tooltip = {'Introduzca su código ID por favor.'};
            app.ImgID.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'id.png');
            app.ImgCorreo = uiimage(app.IniciarSesion);
            app.ImgCorreo.Tooltip = {'Introduzca su correo electrónico por favor.'};
            app.ImgCorreo.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'correo.png');
            app.Ast3 = uilabel(app.IniciarSesion);
            app.Ast3.Tooltip = {'¡Campo obligatorio!'};
            app.Ast3.Text = '*';
            app.EditFieldContrasena = uieditfield(app.IniciarSesion, 'text');
            app.EditFieldContrasena.ValueChangingFcn = createCallbackFcn(app, @EditFieldContrasenaValueChanging, true);
            app.EditFieldContrasena.Tooltip = {'Introduzca su contraseña por favor.'};
            app.EditFieldContrasena.Placeholder = 'Contraseña';
            app.ImgContrasena = uiimage(app.IniciarSesion);
            app.ImgContrasena.Tooltip = {'Introduzca su contraseña por favor.'};
            app.ImgContrasena.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'contraseña.png');
            app.EditFieldError = uilabel(app.IniciarSesion);
            app.EditFieldError.Visible = 'off';
            app.EditFieldError.Text = 'ERROR: ¡Las credenciales de acceso no están registradas!  ';
            app.EditFieldCorrecto = uilabel(app.IniciarSesion);
            app.EditFieldCorrecto.Visible = 'off';
            app.EditFieldCorrecto.Text = 'Las credenciales de acceso introducidas son correctas.       ';
            app.ImgError = uiimage(app.IniciarSesion);
            app.ImgError.Visible = 'off';
            app.ImgError.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'error.png');
            app.ImgCorrecto = uiimage(app.IniciarSesion);
            app.ImgCorrecto.Visible = 'off';
            app.ImgCorrecto.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'correcto.png');
            app.ButtonInicioSesion = uibutton(app.IniciarSesion, 'push');
            app.ButtonInicioSesion.ButtonPushedFcn = createCallbackFcn(app, @ButtonInicioSesionPushed, true);
            app.ButtonInicioSesion.Icon = fullfile(pathToMLAPP, 'Imagenes', 'inicio_sesion.png');
            app.ButtonInicioSesion.Tooltip = {'Pulse para iniciar sesión.'};
            app.ButtonInicioSesion.Text = '    Iniciar Sesión';
            app.ButtonLimpiar = uibutton(app.IniciarSesion, 'push');
            app.ButtonLimpiar.ButtonPushedFcn = createCallbackFcn(app, @ButtonLimpiarButtonPushed, true);
            app.ButtonLimpiar.Icon = fullfile(pathToMLAPP, 'Imagenes', 'basura.png');
            app.ButtonLimpiar.Tooltip = {'Pulse para limpiar los campos.'};
            app.ButtonLimpiar.Text = '   Limpiar Campos';
            app.Instrucciones = uitab(app.Modulos);
            app.Instrucciones.Title = 'Instrucciones';
            app.LabelSuperior2 = uilabel(app.Instrucciones);
            app.LabelSuperior2.Text = '';
            app.LabelBordeSuperior2 = uilabel(app.Instrucciones);
            app.LabelBordeSuperior2.Text = '0';
            app.LabelInferior2 = uilabel(app.Instrucciones);
            app.LabelInferior2.Text = 'Glau D-Tect de VisionHealth Systems';
            app.LabelBordeInferior2 = uilabel(app.Instrucciones);
            app.LabelBordeInferior2.Text = '0';
            app.ImgLogo2 = uiimage(app.Instrucciones);
            app.ImgLogo2.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'logoo.png');
            app.ButtonLogIn = uibutton(app.Instrucciones, 'push');
            app.ButtonLogIn.ButtonPushedFcn = createCallbackFcn(app, @ButtonLogInPushed, true);
            app.ButtonLogIn.Icon = fullfile(pathToMLAPP, 'Imagenes', 'inicio_sesion2.png');
            app.ButtonLogIn.Tooltip = {'Pulse para ir al módulo de inicio de sesión.'};
            app.ButtonLogIn.Text = '';
            app.LabelLogIn = uilabel(app.Instrucciones);
            app.LabelLogIn.Text = 'Iniciar Sesión';
            app.ButtonInstr = uibutton(app.Instrucciones, 'push');
            app.ButtonInstr.ButtonPushedFcn = createCallbackFcn(app, @ButtonInstrPushed, true);
            app.ButtonInstr.Icon = fullfile(pathToMLAPP, 'Imagenes', 'Instrucciones.png');
            app.ButtonInstr.Tooltip = {'Pulse para ir al módulo de instrucciones.'};
            app.ButtonInstr.Text = '';
            app.LabelInstr = uilabel(app.Instrucciones);
            app.LabelInstr.Text = 'Instrucciones';
            app.ButtonGlaucoma = uibutton(app.Instrucciones, 'push');
            app.ButtonGlaucoma.ButtonPushedFcn = createCallbackFcn(app, @ButtonGlaucomaPushed, true);
            app.ButtonGlaucoma.Icon = fullfile(pathToMLAPP, 'Imagenes', 'ojo2.png');
            app.ButtonGlaucoma.Tooltip = {'Pulse para ir al módulo de detección de glaucoma.'};
            app.ButtonGlaucoma.Text = '';
            app.LabelGlaucoma = uilabel(app.Instrucciones);
            app.LabelGlaucoma.Text = {'Detección'; 'Glaucoma'};
            app.ButtonInforme = uibutton(app.Instrucciones, 'push');
            app.ButtonInforme.ButtonPushedFcn = createCallbackFcn(app, @ButtonInformePushed, true);
            app.ButtonInforme.Icon = fullfile(pathToMLAPP, 'Imagenes', 'Portapapeles.png');
            app.ButtonInforme.Tooltip = {'Pulse para ir al módulo de creación de informe.'};
            app.ButtonInforme.Text = '';
            app.LabelInf = uilabel(app.Instrucciones);
            app.LabelInf.Text = 'Crear Informe';
            app.ButtonAcercaDe = uibutton(app.Instrucciones, 'push');
            app.ButtonAcercaDe.ButtonPushedFcn = createCallbackFcn(app, @ButtonAcercaDePushed, true);
            app.ButtonAcercaDe.Icon = fullfile(pathToMLAPP, 'Imagenes', 'acercade.png');
            app.ButtonAcercaDe.Tooltip = {'Pulse para ir al módulo de acerca de.'};
            app.ButtonAcercaDe.Text = '';
            app.LabelAcercaDe = uilabel(app.Instrucciones);
            app.LabelAcercaDe.Text = 'Acerca de';
            app.ImgDoctor = uiimage(app.Instrucciones);
            app.ImgDoctor.Tooltip = {''};
            app.LabelDoctor = uilabel(app.Instrucciones);
            app.LabelDoctor.Text = '';
            app.LabelID = uilabel(app.Instrucciones);
            app.LabelID.Text = '';
            app.HTMLFechaHora = uihtml(app.Instrucciones);
            app.HTMLFechaHora.HTMLSource = 'html/fecha_hora.html';
            app.HTMLFechaHora.Tooltip = {'Fecha y hora.'};
            app.ImgCandado = uiimage(app.Instrucciones);
            app.ImgCandado.ImageClickedFcn = createCallbackFcn(app, @ImgCandadoImageClicked2, true);
            app.ImgCandado.Tooltip = {'Pulse para cerrar la aplicación.'};
            app.ImgCandado.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'candado.png');
            app.LabelCerrarApp = uilabel(app.Instrucciones);
            app.LabelCerrarApp.Text = 'Cerrar App';
            app.ImgPaginaWeb = uiimage(app.Instrucciones);
            app.ImgPaginaWeb.ImageClickedFcn = createCallbackFcn(app, @ImgPaginaWebImageClicked, true);
            app.ImgPaginaWeb.Tooltip = {'Pulse para acceder a la página web.'};
            app.ImgPaginaWeb.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'web.png');
            app.LabelPaginaWeb = uilabel(app.Instrucciones);
            app.LabelPaginaWeb.Text = 'Página Web';
            app.HTMLRectInstrucciones = uihtml(app.Instrucciones);
            app.HTMLRectInstrucciones.HTMLSource = 'html/rect2.html';
            app.ImgInstrucciones = uiimage(app.Instrucciones);
            app.ImgInstrucciones.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'Instr.png');
            app.LabelInstrucciones4 = uilabel(app.Instrucciones);
            app.LabelInstrucciones4.Text = 'Si desea tener acceso a las instrucciones completas, por favor ';
            app.HyperlinkInstrucciones = uihyperlink(app.Instrucciones);
            app.HyperlinkInstrucciones.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkInstruccionesClicked, true);
            app.HyperlinkInstrucciones.Tooltip = {'Pulse para acceder a las instrucciones de uso al completo.'};
            app.HyperlinkInstrucciones.Text = 'pulse aquí.';
            app.LabelInstrucciones3 = uilabel(app.Instrucciones);
            app.LabelInstrucciones3.Text = {'-Para empezar a usar la aplicación, vaya al módulo de "Detección de Glaucoma". En dicho módulo, tiene la opción de '; 'cargar una única imagen de fondo de ojo o de cargar un repositorio de imágenes del mismo paciente.'; '-Para determinar la calidad de la imagen seleccionada, pulse el botón de "Determinar calidad". Si la calidad de la imagen es '; 'correcta, la lámpara se iluminará en color verde, y si por el contrario la calidad de la imagen no es correcta, se iluminara en '; 'color rojo. Los valores de las métricas de calidad de la imagen estarán visibles una vez determinada la calidad.'; '-Una vez determinada la calidad de la imagen, si esta es de calidad correcta, se podrá proceder a segmentar el disco óptico'; 'y a determinar si el paciente tiene glaucoma o no.'; '-En caso de que no se haya podido segmentar el disco óptico automáticamente, se pedirá al usuario que seleccione el disco'; 'óptico manualmente.'; '-Para crear un informe de la sesión, vaya al módulo de "Crear Informe". Para comenzar, pulse el botón de iniciar informe y '; 'especifique la fecha. En el campo de comentarios, escriba la información adicional que crea conveniente y pulse el botón '; 'de crear informe. De esta forma se creará y guardará una tabla con la información de la calidad de la imagen, el diagnóstico '; 'y los comentarios adicionales escritos, así como un informe con imágenes relevantes.'; '-Por último, vaya al módulo de "Acerca de" para saber más sobre la empresa, su equipo de desarrollo y la comercialización'; ' del software.'; '-Para pasar de un módulo a otro, pulse los botones que se encuentran en la parte superior de la pantalla. Asimismo, si'; 'desea cerrar la aplicación o acceder a la página web pulse los botones de la parte superior derecha de la aplicación.'};
            app.LabelInstrucciones2 = uilabel(app.Instrucciones);
            app.LabelInstrucciones2.Text = 'Para un uso correcto del software, por favor siga las instrucciones de a continuación:';
            app.LabelInstrucciones1 = uilabel(app.Instrucciones);
            app.LabelInstrucciones1.Text = 'Instrucciones de uso del software';
            app.DeteccionGlaucoma = uitab(app.Modulos);
            app.DeteccionGlaucoma.Title = 'DetecciónGlaucoma';
            app.Histogram = uiaxes(app.DeteccionGlaucoma);
            title(app.Histogram, 'Histograma')
            xlabel(app.Histogram, 'Intensidad de píxel')
            ylabel(app.Histogram, 'Frecuencia de píxel')
            app.Histogram.XTick = [0 25 50 75 100 125 150 175 200 225 255];
            app.Histogram.XTickLabel = {'0'; '25'; '50'; '75'; '100'; '125'; '150'; '175'; '200'; '225'; '255'};
            app.LabelSuperior3 = uilabel(app.DeteccionGlaucoma);
            app.LabelSuperior3.Text = '';
            app.LabelBordeSuperior3 = uilabel(app.DeteccionGlaucoma);
            app.LabelBordeSuperior3.Text = '0';
            app.LabelInferior3 = uilabel(app.DeteccionGlaucoma);
            app.LabelInferior3.Text = 'Glau D-Tect de VisionHealth Systems';
            app.LabelBordeInferior3 = uilabel(app.DeteccionGlaucoma);
            app.LabelBordeInferior3.Text = '0';
            app.ImgLogo3 = uiimage(app.DeteccionGlaucoma);
            app.ImgLogo3.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'logoo.png');
            app.HTMLRect2 = uihtml(app.DeteccionGlaucoma);
            app.HTMLRect2.HTMLSource = 'html/rect.html';
            app.ButtonLogIn2 = uibutton(app.DeteccionGlaucoma, 'push');
            app.ButtonLogIn2.ButtonPushedFcn = createCallbackFcn(app, @ButtonLogIn2Pushed, true);
            app.ButtonLogIn2.Icon = fullfile(pathToMLAPP, 'Imagenes', 'inicio_sesion2.png');
            app.ButtonLogIn2.Tooltip = {'Pulse para ir al módulo de inicio de sesión.'};
            app.ButtonLogIn2.Text = '';
            app.LabelLogIn2 = uilabel(app.DeteccionGlaucoma);
            app.LabelLogIn2.Text = 'Iniciar Sesión';
            app.ButtonInstr2 = uibutton(app.DeteccionGlaucoma, 'push');
            app.ButtonInstr2.ButtonPushedFcn = createCallbackFcn(app, @ButtonInstr2Pushed, true);
            app.ButtonInstr2.Icon = fullfile(pathToMLAPP, 'Imagenes', 'Instrucciones.png');
            app.ButtonInstr2.Tooltip = {'Pulse para ir al módulo de instrucciones.'};
            app.ButtonInstr2.Text = '';
            app.LabelInstr2 = uilabel(app.DeteccionGlaucoma);
            app.LabelInstr2.Text = 'Instrucciones';
            app.ButtonGlaucoma2 = uibutton(app.DeteccionGlaucoma, 'push');
            app.ButtonGlaucoma2.ButtonPushedFcn = createCallbackFcn(app, @ButtonGlaucoma2Pushed, true);
            app.ButtonGlaucoma2.Icon = fullfile(pathToMLAPP, 'Imagenes', 'ojo2.png');
            app.ButtonGlaucoma2.Tooltip = {'Pulse para ir al módulo de detección de glaucoma.'};
            app.ButtonGlaucoma2.Text = '';
            app.LabelGlaucoma2 = uilabel(app.DeteccionGlaucoma);
            app.LabelGlaucoma2.Text = {'Detección'; 'Glaucoma'};
            app.ButtonInforme2 = uibutton(app.DeteccionGlaucoma, 'push');
            app.ButtonInforme2.ButtonPushedFcn = createCallbackFcn(app, @ButtonInforme2Pushed, true);
            app.ButtonInforme2.Icon = fullfile(pathToMLAPP, 'Imagenes', 'Portapapeles.png');
            app.ButtonInforme2.Tooltip = {'Pulse para ir al módulo de creación de informe.'};
            app.ButtonInforme2.Text = '';
            app.LabelInf2 = uilabel(app.DeteccionGlaucoma);
            app.LabelInf2.Text = 'Crear Informe';
            app.ButtonAcercaDe2 = uibutton(app.DeteccionGlaucoma, 'push');
            app.ButtonAcercaDe2.ButtonPushedFcn = createCallbackFcn(app, @ButtonAcercaDe2Pushed, true);
            app.ButtonAcercaDe2.Icon = fullfile(pathToMLAPP, 'Imagenes', 'acercade.png');
            app.ButtonAcercaDe2.Tooltip = {'Pulse para ir al módulo de acerca de.'};
            app.ButtonAcercaDe2.Text = '';
            app.LabelAcercaDe2 = uilabel(app.DeteccionGlaucoma);
            app.LabelAcercaDe2.Text = 'Acerca de';
            app.ImgDoctor2 = uiimage(app.DeteccionGlaucoma);
            app.ImgDoctor2.ImageClickedFcn = createCallbackFcn(app, @ImgDoctor2ImageClicked, true);
            app.ImgDoctor2.Tooltip = {''};
            app.LabelDoctor2 = uilabel(app.DeteccionGlaucoma);
            app.LabelDoctor2.Text = '';
            app.LabelID2 = uilabel(app.DeteccionGlaucoma);
            app.LabelID2.Text = '';
            app.HTMLFechaHora2 = uihtml(app.DeteccionGlaucoma);
            app.HTMLFechaHora2.HTMLSource = 'html/fecha_hora.html';
            app.HTMLFechaHora2.Tooltip = {'Fecha y hora.'};
            app.ImgCandado2 = uiimage(app.DeteccionGlaucoma);
            app.ImgCandado2.ImageClickedFcn = createCallbackFcn(app, @ImgCandado2ImageClicked2, true);
            app.ImgCandado2.Tooltip = {'Pulse para cerrar la aplicación.'};
            app.ImgCandado2.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'candado.png');
            app.LabelCerrarApp2 = uilabel(app.DeteccionGlaucoma);
            app.LabelCerrarApp2.Text = 'Cerrar App';
            app.ImgPaginaWeb2 = uiimage(app.DeteccionGlaucoma);
            app.ImgPaginaWeb2.ImageClickedFcn = createCallbackFcn(app, @ImgPaginaWeb2ImageClicked, true);
            app.ImgPaginaWeb2.Tooltip = {'Pulse para acceder a la página web.'};
            app.ImgPaginaWeb2.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'web.png');
            app.LabelPaginaWeb2 = uilabel(app.DeteccionGlaucoma);
            app.LabelPaginaWeb2.Text = 'Página Web';
            app.DropDownElegirCanal = uidropdown(app.DeteccionGlaucoma);
            app.DropDownElegirCanal.Items = {'Color', 'Gris', 'Rojo', 'Verde', 'Azul'};
            app.DropDownElegirCanal.ValueChangedFcn = createCallbackFcn(app, @DropDownElegirCanalValueChanged, true);
            app.DropDownElegirCanal.Tooltip = {'Seleccione si desea visualizar la imagen a color, en escala de grises o si desea visualizar los diferentes canales de la imagen.'};
            app.DropDownElegirCanal.Value = 'Color';
            app.ElegirCanaldeimagenLabel = uilabel(app.DeteccionGlaucoma);
            app.ElegirCanaldeimagenLabel.Tooltip = {'Seleccione si desea visualizar la imagen a color, en escala de grises o si desea visualizar los diferentes canales de la imagen.'};
            app.ElegirCanaldeimagenLabel.Text = 'Elegir Canal:';
            app.Lamp = uilamp(app.DeteccionGlaucoma);
            app.ImgRFI = uiimage(app.DeteccionGlaucoma);
            app.ImgRFI.Tooltip = {'Imágen de fondo de ojo.'};
            app.EditFieldImagen = uilabel(app.DeteccionGlaucoma);
            app.EditFieldImagen.Text = 'Nombre de Imagen';
            app.TreeRepositorio = uitree(app.DeteccionGlaucoma);
            app.TreeRepositorio.SelectionChangedFcn = createCallbackFcn(app, @TreeRepositorioSelectionChanged, true);
            app.NodeRepositorio = uitreenode(app.TreeRepositorio);
            app.NodeRepositorio.Icon = fullfile(pathToMLAPP, 'Imagenes', 'imagen.png');
            app.NodeRepositorio.Text = 'Repositorios de imágenes:';
            app.ButtonAnadirRepositorio = uibutton(app.DeteccionGlaucoma, 'push');
            app.ButtonAnadirRepositorio.ButtonPushedFcn = createCallbackFcn(app, @ButtonAnadirRepositorioPushed, true);
            app.ButtonAnadirRepositorio.Icon = fullfile(pathToMLAPP, 'Imagenes', 'carpeta.png');
            app.ButtonAnadirRepositorio.Tooltip = {'Pulse para seleccionar y añadir un repositorio de imágenes.'};
            app.ButtonAnadirRepositorio.Text = 'Añadir repositorio';
            app.EditFieldCarga = uilabel(app.DeteccionGlaucoma);
            app.EditFieldCarga.Text = '         ¡Cargando el repositorio!  ';
            app.ImgCarga = uiimage(app.DeteccionGlaucoma);
            app.ImgCarga.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'carga.gif');
            app.ButtonAnadirImagen = uibutton(app.DeteccionGlaucoma, 'push');
            app.ButtonAnadirImagen.ButtonPushedFcn = createCallbackFcn(app, @ButtonAnadirImagenButtonPushed, true);
            app.ButtonAnadirImagen.Icon = fullfile(pathToMLAPP, 'Imagenes', 'imagen2.png');
            app.ButtonAnadirImagen.Tooltip = {'Pulse para seleccionar y añadir un repositorio de imágenes.'};
            app.ButtonAnadirImagen.Text = 'Añadir imagen';
            app.ButtonDeterminarCalidad = uibutton(app.DeteccionGlaucoma, 'push');
            app.ButtonDeterminarCalidad.ButtonPushedFcn = createCallbackFcn(app, @ButtonDeterminarCalidadPushed, true);
            app.ButtonDeterminarCalidad.Icon = fullfile(pathToMLAPP, 'Imagenes', 'calidad.png');
            app.ButtonDeterminarCalidad.Tooltip = {'Pulse para determinar la calidad de la imagen.'};
            app.ButtonDeterminarCalidad.Text = 'Determinar calidad';
            app.LabelCalidad = uilabel(app.DeteccionGlaucoma);
            app.LabelCalidad.Tooltip = {'Calidad de la imagen.'};
            app.LabelCalidad.Text = '';
            app.LampCalidad = uilamp(app.DeteccionGlaucoma);
            app.LapMaxEditField = uieditfield(app.DeteccionGlaucoma, 'text');
            app.LapMaxEditField.Editable = 'off';
            app.LapMedEditField = uieditfield(app.DeteccionGlaucoma, 'text');
            app.LapMedEditField.Editable = 'off';
            app.LapDesEditField = uieditfield(app.DeteccionGlaucoma, 'text');
            app.LapDesEditField.Editable = 'off';
            app.EntropiaLabel = uilabel(app.DeteccionGlaucoma);
            app.EntropiaLabel.Tooltip = {'La entropía de una imagen es una medida estadística que se utiliza para caracterizar la textura de la imagen'};
            app.EntropiaLabel.Text = 'Entropia:';
            app.EntropiaEditField = uieditfield(app.DeteccionGlaucoma, 'text');
            app.EntropiaEditField.Editable = 'off';
            app.EntropiaEditField.Tooltip = {''};
            app.RangoDinmicoLabel = uilabel(app.DeteccionGlaucoma);
            app.RangoDinmicoLabel.Tooltip = {'El rango dinámico es la diferencia entre el valor máximo o más claro de la imagen y el valor mínimo o más oscuro de la imagen.'};
            app.RangoDinmicoLabel.Text = 'Rango Dinámico:';
            app.RangoDinamicoEditField = uieditfield(app.DeteccionGlaucoma, 'text');
            app.RangoDinamicoEditField.Editable = 'off';
            app.RangoDinamicoEditField.Tooltip = {''};
            app.ContrasteLabel = uilabel(app.DeteccionGlaucoma);
            app.ContrasteLabel.Tooltip = {'El contraste es una medida de la variación de la intensidad de la luz en la imagen.'};
            app.ContrasteLabel.Text = 'Contraste:';
            app.ContrasteEditField = uieditfield(app.DeteccionGlaucoma, 'text');
            app.ContrasteEditField.Editable = 'off';
            app.MximoLaplacianaLabel = uilabel(app.DeteccionGlaucoma);
            app.MximoLaplacianaLabel.Tooltip = {'El operador laplaciano aplicado a una imagen es una técnica que resalta los bordes y áreas de cambio abrupto en una imagen.'};
            app.MximoLaplacianaLabel.Text = 'Máximo Laplaciana:';
            app.MximoLaplacianaLabel_2 = uilabel(app.DeteccionGlaucoma);
            app.MximoLaplacianaLabel_2.Tooltip = {'El operador laplaciano aplicado a una imagen es una técnica que resalta los bordes y áreas de cambio abrupto en una imagen'};
            app.MximoLaplacianaLabel_2.Text = 'Media Laplaciana:';
            app.MximoLaplacianaLabel_3 = uilabel(app.DeteccionGlaucoma);
            app.MximoLaplacianaLabel_3.Tooltip = {'El operador laplaciano aplicado a una imagen es una técnica que resalta los bordes y áreas de cambio abrupto en una imagen'};
            app.MximoLaplacianaLabel_3.Text = 'Desviación Laplaciana:';
            app.ImgOD = uiimage(app.DeteccionGlaucoma);
            app.HTMLRect2_2 = uihtml(app.DeteccionGlaucoma);
            app.HTMLRect2_2.HTMLSource = 'html/rect3.html';
            app.ButtonSegmentar = uibutton(app.DeteccionGlaucoma, 'push');
            app.ButtonSegmentar.ButtonPushedFcn = createCallbackFcn(app, @ButtonSegmentarPushed, true);
            app.ButtonSegmentar.Icon = fullfile(pathToMLAPP, 'Imagenes', 'circulo.png');
            app.ButtonSegmentar.Text = 'Segmentar disco óptico';
            app.ButtonTrash = uibutton(app.DeteccionGlaucoma, 'push');
            app.ButtonTrash.ButtonPushedFcn = createCallbackFcn(app, @ButtonTrashPushed, true);
            app.ButtonTrash.Icon = fullfile(pathToMLAPP, 'Imagenes', 'trash.png');
            app.ButtonTrash.Tooltip = {'Pulse para limpiar los campos'};
            app.ButtonTrash.Text = 'Limpiar campos';
            app.ButtonSegmentar_2 = uibutton(app.DeteccionGlaucoma, 'push');
            app.ButtonSegmentar_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonSegmentar_2Pushed, true);
            app.ButtonSegmentar_2.Icon = fullfile(pathToMLAPP, 'Imagenes', 'eye.png');
            app.ButtonSegmentar_2.Text = 'Determinar diagnóstico';
            app.LabelDiagnostico = uilabel(app.DeteccionGlaucoma);
            app.LabelDiagnostico.Tooltip = {'Calidad de la imagen.'};
            app.LabelDiagnostico.Text = '';
            app.LampDiagnostico = uilamp(app.DeteccionGlaucoma);
            app.DiscopticoDropDown = uidropdown(app.DeteccionGlaucoma);
            app.DiscopticoDropDown.Items = {'Vasculatura', 'ROI', 'Disco óptico'};
            app.DiscopticoDropDown.ValueChangedFcn = createCallbackFcn(app, @DiscopticoDropDownValueChanged, true);
            app.DiscopticoDropDown.Value = 'Disco óptico';
            app.LabelProbabilidad = uilabel(app.DeteccionGlaucoma);
            app.LabelProbabilidad.Tooltip = {'Calidad de la imagen.'};
            app.LabelProbabilidad.Text = '';
            app.ButtonSegmentar_3 = uibutton(app.DeteccionGlaucoma, 'push');
            app.ButtonSegmentar_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonSegmentar_3Pushed, true);
            app.ButtonSegmentar_3.Icon = fullfile(pathToMLAPP, 'Imagenes', 'porc.png');
            app.ButtonSegmentar_3.Text = 'Probabilidad de glaucoma';
            app.CrearInforme = uitab(app.Modulos);
            app.CrearInforme.Title = 'CreacionInforme';
            app.LabelSuperior4 = uilabel(app.CrearInforme);
            app.LabelSuperior4.Text = '';
            app.LabelBordeSuperior4 = uilabel(app.CrearInforme);
            app.LabelBordeSuperior4.Text = '0';
            app.LabelInferior4 = uilabel(app.CrearInforme);
            app.LabelInferior4.Text = 'Glau D-Tect de VisionHealth Systems';
            app.LabelBordeInferior4 = uilabel(app.CrearInforme);
            app.LabelBordeInferior4.Text = '0';
            app.ImgLogo4 = uiimage(app.CrearInforme);
            app.ImgLogo4.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'logoo.png');
            app.HTMLRectInforme = uihtml(app.CrearInforme);
            app.HTMLRectInforme.HTMLSource = 'html/rect2.html';
            app.ButtonLogIn3 = uibutton(app.CrearInforme, 'push');
            app.ButtonLogIn3.ButtonPushedFcn = createCallbackFcn(app, @ButtonLogIn3Pushed, true);
            app.ButtonLogIn3.Icon = fullfile(pathToMLAPP, 'Imagenes', 'inicio_sesion2.png');
            app.ButtonLogIn3.Tooltip = {'Pulse para ir al módulo de inicio de sesión.'};
            app.ButtonLogIn3.Text = '';
            app.LabelLogIn3 = uilabel(app.CrearInforme);
            app.LabelLogIn3.Text = 'Iniciar Sesión';
            app.ButtonInstr3 = uibutton(app.CrearInforme, 'push');
            app.ButtonInstr3.ButtonPushedFcn = createCallbackFcn(app, @ButtonInstr3Pushed, true);
            app.ButtonInstr3.Icon = fullfile(pathToMLAPP, 'Imagenes', 'Instrucciones.png');
            app.ButtonInstr3.Tooltip = {'Pulse para ir al módulo de instrucciones.'};
            app.ButtonInstr3.Text = '';
            app.LabelInstr3 = uilabel(app.CrearInforme);
            app.LabelInstr3.Text = 'Instrucciones';
            app.ButtonGlaucoma3 = uibutton(app.CrearInforme, 'push');
            app.ButtonGlaucoma3.ButtonPushedFcn = createCallbackFcn(app, @ButtonGlaucoma3Pushed, true);
            app.ButtonGlaucoma3.Icon = fullfile(pathToMLAPP, 'Imagenes', 'ojo2.png');
            app.ButtonGlaucoma3.Tooltip = {'Pulse para ir al módulo de detección de glaucoma.'};
            app.ButtonGlaucoma3.Text = '';
            app.LabelGlaucoma3 = uilabel(app.CrearInforme);
            app.LabelGlaucoma3.Text = {'Detección'; 'Glaucoma'};
            app.ButtonInforme3 = uibutton(app.CrearInforme, 'push');
            app.ButtonInforme3.ButtonPushedFcn = createCallbackFcn(app, @ButtonInforme3Pushed, true);
            app.ButtonInforme3.Icon = fullfile(pathToMLAPP, 'Imagenes', 'Portapapeles.png');
            app.ButtonInforme3.Tooltip = {'Pulse para ir al módulo de creación de informe.'};
            app.ButtonInforme3.Text = '';
            app.LabelInforme3 = uilabel(app.CrearInforme);
            app.LabelInforme3.Text = 'Crear Informe';
            app.ButtonAcercaDe3 = uibutton(app.CrearInforme, 'push');
            app.ButtonAcercaDe3.ButtonPushedFcn = createCallbackFcn(app, @ButtonAcercaDe3Pushed, true);
            app.ButtonAcercaDe3.Icon = fullfile(pathToMLAPP, 'Imagenes', 'acercade.png');
            app.ButtonAcercaDe3.Tooltip = {'Pulse para ir al módulo de acerca de.'};
            app.ButtonAcercaDe3.Text = '';
            app.LabelAcercaDe3 = uilabel(app.CrearInforme);
            app.LabelAcercaDe3.Text = 'Acerca de';
            app.ImgDoctor3 = uiimage(app.CrearInforme);
            app.ImgDoctor3.ImageClickedFcn = createCallbackFcn(app, @ImgDoctor3ImageClicked, true);
            app.ImgDoctor3.Tooltip = {''};
            app.LabelDoctor3 = uilabel(app.CrearInforme);
            app.LabelDoctor3.Text = '';
            app.LabelID3 = uilabel(app.CrearInforme);
            app.LabelID3.Text = '';
            app.HTMLFechaHora3 = uihtml(app.CrearInforme);
            app.HTMLFechaHora3.HTMLSource = 'html/fecha_hora.html';
            app.HTMLFechaHora3.Tooltip = {'Fecha y hora.'};
            app.ImgCandado3 = uiimage(app.CrearInforme);
            app.ImgCandado3.ImageClickedFcn = createCallbackFcn(app, @ImgCandado3ImageClicked, true);
            app.ImgCandado3.Tooltip = {'Pulse para cerrar la aplicación.'};
            app.ImgCandado3.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'candado.png');
            app.LabelCerrarApp3 = uilabel(app.CrearInforme);
            app.LabelCerrarApp3.Text = 'Cerrar App';
            app.ImgPaginaWeb3 = uiimage(app.CrearInforme);
            app.ImgPaginaWeb3.ImageClickedFcn = createCallbackFcn(app, @ImgPaginaWeb3ImageClicked, true);
            app.ImgPaginaWeb3.Tooltip = {'Pulse para acceder a la página web.'};
            app.ImgPaginaWeb3.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'web.png');
            app.LabelPaginaWeb3 = uilabel(app.CrearInforme);
            app.LabelPaginaWeb3.Text = 'Página Web';
            app.ImgInforme = uiimage(app.CrearInforme);
            app.ImgInforme.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'clipboard.png');
            app.LabelInforme1 = uilabel(app.CrearInforme);
            app.LabelInforme1.Text = 'Crear Informe de la sesión';
            app.LabelInforme2 = uilabel(app.CrearInforme);
            app.LabelInforme2.Text = {'Para crear un informe de la sesión actual, pulse el botón de iniciar informe, seleccione la fecha de la sesión, escriba '; 'comentarios si lo ve oportuno y posteriormente pulse el botón de guardar informe.'};
            app.ButtonIniciarInforme = uibutton(app.CrearInforme, 'push');
            app.ButtonIniciarInforme.ButtonPushedFcn = createCallbackFcn(app, @ButtonIniciarInformeButtonPushed, true);
            app.ButtonIniciarInforme.Icon = fullfile(pathToMLAPP, 'Imagenes', 'port.png');
            app.ButtonIniciarInforme.Tooltip = {'Pulse para iniciar el informe.'};
            app.ButtonIniciarInforme.Text = 'Iniciar Informe';
            app.ImagenEditFieldLabel = uilabel(app.CrearInforme);
            app.ImagenEditFieldLabel.Text = 'Imagen';
            app.ImagenEditField = uieditfield(app.CrearInforme, 'text');
            app.ImagenEditField.Editable = 'off';
            app.ImagenEditField.Tooltip = {'Imagen actual'};
            app.ImgImagen = uiimage(app.CrearInforme);
            app.ImgImagen.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'img.png');
            app.EspecialistaEditFieldLabel = uilabel(app.CrearInforme);
            app.EspecialistaEditFieldLabel.Text = 'Especialista';
            app.EditFieldEspecialista = uieditfield(app.CrearInforme, 'text');
            app.EditFieldEspecialista.Editable = 'off';
            app.EditFieldEspecialista.Tooltip = {'Especialista.'};
            app.ImgEspecialista = uiimage(app.CrearInforme);
            app.ImgEspecialista.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'doc.png');
            app.FechaDatePickerLabel = uilabel(app.CrearInforme);
            app.FechaDatePickerLabel.Text = 'Fecha';
            app.DatePickerFecha = uidatepicker(app.CrearInforme);
            app.DatePickerFecha.DisplayFormat = 'dd/MMM/uuuu';
            app.DatePickerFecha.Tooltip = {'Seleccione la fecha de la sesión por favor.'};
            app.ImgFecha = uiimage(app.CrearInforme);
            app.ImgFecha.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'fecha.png');
            app.ButtonGuardarInforme = uibutton(app.CrearInforme, 'push');
            app.ButtonGuardarInforme.ButtonPushedFcn = createCallbackFcn(app, @ButtonGuardarInformeButtonPushed, true);
            app.ButtonGuardarInforme.Icon = fullfile(pathToMLAPP, 'Imagenes', 'port.png');
            app.ButtonGuardarInforme.Tooltip = {'Pulse para guardar el informe.'};
            app.ButtonGuardarInforme.Text = 'Guardar Informe';
            app.ImgComentarios = uiimage(app.CrearInforme);
            app.ImgComentarios.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'comentario.png');
            app.TextAreaComentarios = uitextarea(app.CrearInforme);
            app.LabelComentarios = uilabel(app.CrearInforme);
            app.LabelComentarios.Text = 'Comentarios Adicionales';
            app.AcercaDe = uitab(app.Modulos);
            app.AcercaDe.Title = 'AcercaDe';
            app.LabelSuperior5 = uilabel(app.AcercaDe);
            app.LabelSuperior5.Text = '';
            app.LabelBordeSuperior5 = uilabel(app.AcercaDe);
            app.LabelBordeSuperior5.Text = '0';
            app.LabelInferior5 = uilabel(app.AcercaDe);
            app.LabelInferior5.Text = 'Glau D-Tect de VisionHealth Systems';
            app.LabelBordeInferior5 = uilabel(app.AcercaDe);
            app.LabelBordeInferior5.Text = '0';
            app.ImgLogo5 = uiimage(app.AcercaDe);
            app.ImgLogo5.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'logoo.png');
            app.ButtonLogIn4 = uibutton(app.AcercaDe, 'push');
            app.ButtonLogIn4.ButtonPushedFcn = createCallbackFcn(app, @ButtonLogIn4Pushed, true);
            app.ButtonLogIn4.Icon = fullfile(pathToMLAPP, 'Imagenes', 'inicio_sesion2.png');
            app.ButtonLogIn4.Tooltip = {'Pulse para ir al módulo de inicio de sesión.'};
            app.ButtonLogIn4.Text = '';
            app.LabelLogIn4 = uilabel(app.AcercaDe);
            app.LabelLogIn4.Text = 'Iniciar Sesión';
            app.ButtonInstr4 = uibutton(app.AcercaDe, 'push');
            app.ButtonInstr4.ButtonPushedFcn = createCallbackFcn(app, @ButtonInstr4Pushed, true);
            app.ButtonInstr4.Icon = fullfile(pathToMLAPP, 'Imagenes', 'Instrucciones.png');
            app.ButtonInstr4.Tooltip = {'Pulse para ir al módulo de instrucciones.'};
            app.ButtonInstr4.Text = '';
            app.LabelInstr4 = uilabel(app.AcercaDe);
            app.LabelInstr4.Text = 'Instrucciones';
            app.ButtonGlaucoma4 = uibutton(app.AcercaDe, 'push');
            app.ButtonGlaucoma4.ButtonPushedFcn = createCallbackFcn(app, @ButtonGlaucoma4Pushed, true);
            app.ButtonGlaucoma4.Icon = fullfile(pathToMLAPP, 'Imagenes', 'ojo2.png');
            app.ButtonGlaucoma4.Tooltip = {'Pulse para ir al módulo de detección de glaucoma.'};
            app.ButtonGlaucoma4.Text = '';
            app.LabelGlaucoma4 = uilabel(app.AcercaDe);
            app.LabelGlaucoma4.Text = {'Detección'; 'Glaucoma'};
            app.ButtonInforme4 = uibutton(app.AcercaDe, 'push');
            app.ButtonInforme4.ButtonPushedFcn = createCallbackFcn(app, @ButtonInforme4Pushed, true);
            app.ButtonInforme4.Icon = fullfile(pathToMLAPP, 'Imagenes', 'Portapapeles.png');
            app.ButtonInforme4.Tooltip = {'Pulse para ir al módulo de creación de informe.'};
            app.ButtonInforme4.Text = '';
            app.LabelInforme4 = uilabel(app.AcercaDe);
            app.LabelInforme4.Text = 'Crear Informe';
            app.ButtonAcercaDe4 = uibutton(app.AcercaDe, 'push');
            app.ButtonAcercaDe4.ButtonPushedFcn = createCallbackFcn(app, @ButtonAcercaDe4Pushed, true);
            app.ButtonAcercaDe4.Icon = fullfile(pathToMLAPP, 'Imagenes', 'acercade.png');
            app.ButtonAcercaDe4.Tooltip = {'Pulse para ir al módulo de acerca de.'};
            app.ButtonAcercaDe4.Text = '';
            app.LabelAcercaDe4 = uilabel(app.AcercaDe);
            app.LabelAcercaDe4.Text = 'Acerca de';
            app.ImgDoctor4 = uiimage(app.AcercaDe);
            app.ImgDoctor4.Tooltip = {''};
            app.LabelDoctor4 = uilabel(app.AcercaDe);
            app.LabelDoctor4.Text = '';
            app.LabelID4 = uilabel(app.AcercaDe);
            app.LabelID4.Text = '';
            app.HTMLFechaHora4 = uihtml(app.AcercaDe);
            app.HTMLFechaHora4.HTMLSource = 'html/fecha_hora.html';
            app.HTMLFechaHora4.Tooltip = {'Fecha y hora.'};
            app.ImgCandado4 = uiimage(app.AcercaDe);
            app.ImgCandado4.ImageClickedFcn = createCallbackFcn(app, @ImgCandado4ImageClicked, true);
            app.ImgCandado4.Tooltip = {'Pulse para cerrar la aplicación.'};
            app.ImgCandado4.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'candado.png');
            app.LabelCerrarApp4 = uilabel(app.AcercaDe);
            app.LabelCerrarApp4.Text = 'Cerrar App';
            app.ImgPaginaWeb4 = uiimage(app.AcercaDe);
            app.ImgPaginaWeb4.ImageClickedFcn = createCallbackFcn(app, @ImgPaginaWeb4ImageClicked, true);
            app.ImgPaginaWeb4.Tooltip = {'Pulse para acceder a la página web.'};
            app.ImgPaginaWeb4.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'web.png');
            app.LabelPaginaWeb4 = uilabel(app.AcercaDe);
            app.LabelPaginaWeb4.Text = 'Página Web';
            app.HTMLRectInstr4 = uihtml(app.AcercaDe);
            app.HTMLRectInstr4.HTMLSource = 'html/rect2.html';
            app.ImgPerson = uiimage(app.AcercaDe);
            app.ImgPerson.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'persona.png');
            app.LabelQuienesSomos = uilabel(app.AcercaDe);
            app.LabelQuienesSomos.Text = '¿Quiénes somos?';
            app.LabelQuienesSomos2 = uilabel(app.AcercaDe);
            app.LabelQuienesSomos2.Text = {'VisionHealth Systems es un grupo formado por profesionales apasionados '; 'por mejorar la salud visual a través de la innovación tecnológica. Por ende, '; 'hemos desarrollado un software avanzado para la detección automática del '; 'glaucoma, combinando conocimientos de la biomedicina y la inteligencia artificial. '; 'Nos impulsa el deseo de proporcionar soluciones precisas y accesibles que '; 'transformen la vida de las personas, haciendo posible la detección temprana '; 'del glaucoma y protegiendo la visión de millones de usuarios.'};
            app.ImgLoc = uiimage(app.AcercaDe);
            app.ImgLoc.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'gps.png');
            app.ImgTelephone = uiimage(app.AcercaDe);
            app.ImgTelephone.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'telefono.png');
            app.LabelLocalizacionContacto = uilabel(app.AcercaDe);
            app.LabelLocalizacionContacto.Text = 'Localización y contacto';
            app.ImgMapa = uiimage(app.AcercaDe);
            app.ImgMapa.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'map.png');
            app.LabelLoc = uilabel(app.AcercaDe);
            app.LabelLoc.Text = 'Localización';
            app.LabelLocHorCont = uilabel(app.AcercaDe);
            app.LabelLocHorCont.Text = {'Loramendi Kalea, 4, Arrasate, '; 'Gipuzkoa, España'; ''; 'Lunes-Viernes 9:30-14:30 16:30-20:30'; 'Sábado 9:30-14:30'; 'Domingo CERRADO'; ''; 'Teléfono: +34 944 33 85 32'};
            app.LabelHorario = uilabel(app.AcercaDe);
            app.LabelHorario.Text = 'Horario';
            app.LabelContacto = uilabel(app.AcercaDe);
            app.LabelContacto.Text = 'Contacto';
            app.ImgIbai = uiimage(app.AcercaDe);
            app.ImgIbai.Tooltip = {'Ibai Azpeitia'; 'Técnico del Software'};
            app.ImgIbai.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'ibai.png');
            app.LabelIbai = uilabel(app.AcercaDe);
            app.LabelIbai.Text = {'Ibai Azpeitia'; 'Técnico del Software'};
            app.ImgMarkelO = uiimage(app.AcercaDe);
            app.ImgMarkelO.Tooltip = {'Markel Ocaranza'; 'Especialista en '; 'regulación'};
            app.ImgMarkelO.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'markelo.png');
            app.LabelMarkelO = uilabel(app.AcercaDe);
            app.LabelMarkelO.Text = {'Markel Ocaranza'; 'Especialista en '; 'regulación'};
            app.ImgMarkelP = uiimage(app.AcercaDe);
            app.ImgMarkelP.Tooltip = {'Markel Pastor'; 'Científico de datos'};
            app.ImgMarkelP.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'markelp.png');
            app.LabelMarkelP = uilabel(app.AcercaDe);
            app.LabelMarkelP.Text = {'Markel Pastor'; 'Científico de datos'};
            app.ImgKimetz = uiimage(app.AcercaDe);
            app.ImgKimetz.Tooltip = {'Kimetz Kortazar'; 'Gerente de '; 'marketing y ventas'};
            app.ImgKimetz.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'kimetz.png');
            app.LabelKimetz = uilabel(app.AcercaDe);
            app.LabelKimetz.Text = {'Kimetz Kortazar'; 'Gerente de '; 'marketing y ventas'};
            app.ImgJonan = uiimage(app.AcercaDe);
            app.ImgJonan.Tooltip = {'Jon Ander Fernández '; 'Gerente de producto'};
            app.ImgJonan.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'jonan.png');
            app.LabelJonan = uilabel(app.AcercaDe);
            app.LabelJonan.Text = {'Jon Ander Fernández '; 'Gerente de producto'};
            app.ImgOier = uiimage(app.AcercaDe);
            app.ImgOier.Tooltip = {'Oier Zazpe'; 'Desarrollador y'; 'diseñador web'};
            app.ImgOier.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'oier.png');
            app.LabelOier = uilabel(app.AcercaDe);
            app.LabelOier.Text = {'Oier Zazpe'; 'Desarrollador y'; 'diseñador web'};
            app.LabelMasInfo = uilabel(app.AcercaDe);
            app.LabelMasInfo.Text = {'Si desea acceder a más información acerca de la'; 'comercialización del software o si desea leer las'; 'instrucciones de uso al completo, pulse el siguiente'; 'botón:'};
            app.ButtonMasInfo = uibutton(app.AcercaDe, 'push');
            app.ButtonMasInfo.ButtonPushedFcn = createCallbackFcn(app, @ButtonMasInfoButtonPushed, true);
            app.ButtonMasInfo.Icon = fullfile(pathToMLAPP, 'Imagenes', 'more.png');
            app.ButtonMasInfo.Tooltip = {'Pulse para obtener más información acerca de la comercialización del software.'};
            app.ButtonMasInfo.Text = 'Más información';
            % Show the figure after all components are created
            app.GlauDTect.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Primero

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GlauDTect)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GlauDTect)
        end
    end
end