classdef Principal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GlauDTect                      matlab.ui.Figure
        Modulos                        matlab.ui.container.TabGroup
        IniciarSesion                  matlab.ui.container.Tab
        ButtonAcercaDe1                matlab.ui.control.Button
        LabelInferior                  matlab.ui.control.Label
        LabelBordeInferior             matlab.ui.control.Label
        ButtonLimpiar                  matlab.ui.control.Button
        ButtonInicioSesion             matlab.ui.control.Button
        ImgCorrecto                    matlab.ui.control.Image
        ImgError                       matlab.ui.control.Image
        EditFieldCorrecto              matlab.ui.control.Label
        EditFieldError                 matlab.ui.control.Label
        ImgContrasena                  matlab.ui.control.Image
        EditFieldContrasena            matlab.ui.control.EditField
        Ast3                           matlab.ui.control.Label
        ImgCorreo                      matlab.ui.control.Image
        ImgID                          matlab.ui.control.Image
        EditFieldCorreo                matlab.ui.control.EditField
        EditFieldID                    matlab.ui.control.EditField
        Ast2                           matlab.ui.control.Label
        ImgNombreApellidos             matlab.ui.control.Image
        EditFieldNombreApellidos       matlab.ui.control.EditField
        Ast1                           matlab.ui.control.Label
        LabelInicioDeSesion            matlab.ui.control.Label
        ImgLogo1                       matlab.ui.control.Image
        LabelBienvenido                matlab.ui.control.Label
        HTMLCuadrado                   matlab.ui.control.HTML
        LabelBordeSuperior             matlab.ui.control.Label
        LabelSuperior                  matlab.ui.control.Label
        ImgFondo                       matlab.ui.control.Image
        CribadoCalidad                 matlab.ui.container.Tab
        BotonImagen                    matlab.ui.control.Button
        Label_2                        matlab.ui.control.Label
        Titulo_2                       matlab.ui.control.Label
        ImgCarga                       matlab.ui.control.Image
        EditFieldCarga                 matlab.ui.control.Label
        BotonAnadirRepositorio         matlab.ui.control.Button
        TreeRepositorio                matlab.ui.container.Tree
        NodeRepositorio                matlab.ui.container.TreeNode
        ContrasteEditField             matlab.ui.control.NumericEditField
        ContrasteEditFieldLabel        matlab.ui.control.Label
        LapMediaEditField              matlab.ui.control.NumericEditField
        LapMediaEditFieldLabel         matlab.ui.control.Label
        LapStdEditField                matlab.ui.control.NumericEditField
        LapStdEditFieldLabel           matlab.ui.control.Label
        RangoDinamicoEditField         matlab.ui.control.NumericEditField
        RangoDinamicoEditFieldLabel    matlab.ui.control.Label
        LapMaxEditField                matlab.ui.control.NumericEditField
        LapMaxEditFieldLabel           matlab.ui.control.Label
        EntropiaEditField              matlab.ui.control.NumericEditField
        EntropiaEditFieldLabel         matlab.ui.control.Label
        CalidaddeimagenEditField       matlab.ui.control.EditField
        CalidaddeimagenEditFieldLabel  matlab.ui.control.Label
        ButtonDeterminarCalidad        matlab.ui.control.Button
        ImgRFI                         matlab.ui.control.Image
        Lamp                           matlab.ui.control.Lamp
        DropDownElegirCanal            matlab.ui.control.DropDown
        ElegirCanaldeimagenLabel       matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        AcercadeLabel                  matlab.ui.control.Label
        CrearInformeLabel_2            matlab.ui.control.Label
        CribadoCalidadLabel            matlab.ui.control.Label
        IniciarSesinLabel              matlab.ui.control.Label
        PaginaWebLabel                 matlab.ui.control.Label
        ImgPaginaWeb                   matlab.ui.control.Image
        DeteccinGlaucomaLabel_2        matlab.ui.control.Label
        CerrarSesinLabel               matlab.ui.control.Label
        ImgCandado                     matlab.ui.control.Image
        LabelID                        matlab.ui.control.Label
        ImgLogo2                       matlab.ui.control.Image
        LabelDoctor                    matlab.ui.control.Label
        ButtonAcercaDe                 matlab.ui.control.Button
        ButtonClipBoard                matlab.ui.control.Button
        ButtonGlaucoma                 matlab.ui.control.Button
        ButtonFilter                   matlab.ui.control.Button
        ButtonLogIn                    matlab.ui.control.Button
        ImgDoctor                      matlab.ui.control.Image
        HTMLFechaHora                  matlab.ui.control.HTML
        Titulo                         matlab.ui.control.Label
        Histogram                      matlab.ui.control.UIAxes
        DeteccionGlaucoma              matlab.ui.container.Tab
        CrearInforme                   matlab.ui.container.Tab
        AcercaDe                       matlab.ui.container.Tab
    end

    
    properties (Access = private)
        % Módulo 1, Inicio de Sesión:
        % Tabla de datos de doctores.
        doctores 
        % Sonido de correcto.
        s1 
        Fs1
        % Sonido de error.
        s2 
        Fs2
        % Sonido de botón.
        s3 
        Fs3

        % Módulo 2, Cribado de Calidad:
        % Sonido de cerrar sesión.
        s4
        Fs4
        QualityModel % Modelo de cribado de calidad.
        quality % Celda de codificación de calidad de las imágenes.
        Repositorios % Rutas de los repositorios de imágenes
        imgExtensions % Tipos de extensiones de imágenes.
        ImgActual % Variable para guardar la imagen actual.
    end
    
    methods (Access = private)
         % Función para el cálculo automático de métricas de calidad.
         function [y1, y2, y3, y4, y5, y6] = CalcularMetricasCalidad(~,x)
             x = rgb2gray(x); % Convertir imágen a escala de grises.

             % Parametros para cuantificar contraste:
             y1 = entropy(x); % Calcular la entropia de la imagen.
             y2 = max((x(:))) - min((x(:))); % Calcular el rango dinámico de la imagen.
             y3 = std2(double(x(:))); % Calcular el contraste de la imagen
            
             % Parametros para cuantificar el desenfoque y el ruido:
             laplacian = imfilter(double(x), fspecial('laplacian')); % Calcular la laplaciana de la imagen.
             y4 = max(laplacian(:)); % Calcular el máximo de la laplaciana.
             y5 = mean(laplacian(:)); % Calcular la media de la laplaciana.
             y6 = std(laplacian(:)); % Calcular la desviación estándar de la laplaciana.
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Módulo 1, Inicio de Sesión:
            
            % Cargar imágenes:
            app.ImgFondo.ImageSource = imread(fullfile('..', 'Imagenes', 'fondo.png'));
            app.ImgLogo1.ImageSource = imread(fullfile('..', 'Imagenes', 'logo.png'));
            app.ImgNombreApellidos.ImageSource = imread(fullfile('..', 'Imagenes', 'doctor.png'));
            app.ImgID.ImageSource = imread(fullfile('..', 'Imagenes', 'id.png'));
            app.ImgCorreo.ImageSource = imread(fullfile('..', 'Imagenes', 'correo.png'));
            app.ImgContrasena.ImageSource = imread(fullfile('..', 'Imagenes', 'contraseña.png'));
            app.ImgCorrecto.ImageSource = imread(fullfile('..', 'Imagenes', 'correcto.png'));
            app.ImgError.ImageSource = imread(fullfile('..', 'Imagenes', 'error.png'));

            % Cargar HTML:
            app.HTMLCuadrado.HTMLSource = fullfile('..', 'html', 'square.html');

            % Cargar sonidos:
            correcto = load(fullfile('..', 'Sonidos', 'Correcto.mat'));
            app.s1 = correcto.y;
            app.Fs1 = correcto.Fs;

            error = load(fullfile('..', 'Sonidos', 'error.mat'));
            app.s2 = error.y;
            app.Fs2 = error.Fs;

            boton = load(fullfile('..', 'Sonidos', 'Boton.mat'));
            app.s3 = boton.y;
            app.Fs3 = boton.Fs;

            % Cargar datos de doctores:
            app.doctores = readtable(fullfile('..', 'Doctores', 'Doctores.csv')); % Cargar la tabla de metadata.
            
            % Módulo 2, Cribado de Calidad:

            % Cargar imágenes:
            app.imgExtensions = {'.jpg', '.jpeg', '.png', '.gif', '.bmp', '.tiff'}; % Posibles extensiones de imágenes.

            app.ImgLogo2.ImageSource = imread(fullfile('..', 'Imagenes', 'logo.png'));
            app.ImgCandado.ImageSource = imread(fullfile('..', 'Imagenes', 'candado.png'));
            app.ImgPaginaWeb.ImageSource = imread(fullfile('..', 'Imagenes', 'web.png'));
            app.ImgRFI.Tooltip = 'Seleccione una imagen de fondo de ojo por favor.';

            % Cargar HTML:
            app.HTMLFechaHora.HTMLSource = fullfile('..', 'html', 'fecha_hora.html');

            % Cargar sonidos:
            cerrarSesion = load(fullfile('..', 'Sonidos', 'CerrarSesion.mat'));
            app.s4 = cerrarSesion.y;
            app.Fs4 = cerrarSesion.fs;

            % Cargar el modelo de cribado de calidad:
            load(fullfile('..', '..', 'CribadoCalidad', 'Modelos', 'trainedClassifierSVM.mat'));
            app.QualityModel = trainedClassifierSVM;

            % Definir la codificación de imagenes no revidadas, de bajo contraste, con desenfoque, con ruido y de calidad correcta.
            app.quality = { 
                    0,'Imagen no revisada';
                    1,'Bajo contraste';
                    2,'Desenfoque';
                    3,'Ruido';
                    4,'Calidad correcta'
                    }; 

            % Inicializar la celda para guardar los repositorios:
            app.Repositorios = {};
            
            % Esconder el aviso de carga de repositorio:
            app.ImgCarga.Visible = 'off';
            app.EditFieldCarga.Visible = 'off';

            % Selector de canales:
            
            addStyle(app.DropDownElegirCanal,uistyle("BackgroundColor","#FFFFFF","FontColor","#000000"),"item",1);
            addStyle(app.DropDownElegirCanal,uistyle("BackgroundColor","#808080"),"item",2);
            addStyle(app.DropDownElegirCanal,uistyle("BackgroundColor","#FF0000"),"item",3);
            addStyle(app.DropDownElegirCanal,uistyle("BackgroundColor","#00FF00"),"item",4);
            addStyle(app.DropDownElegirCanal,uistyle("BackgroundColor","#0000FF"),"item",5);

            addStyle(app.NodeRepositorio.Parent, uistyle("FontWeight", "bold"));
        end

        % Callback function
        function ImgCandadoImageClicked(app, event)

        end

        % Callback function
        function DeterminarcalidadButtonPushed(app, event)

        end

        % Button pushed function: BotonAnadirRepositorio
        function BotonAnadirRepositorioPushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.GlauDTect.Pointer = 'watch'; % Poner el cursor en modo espera.
            % Enseñar el aviso de carga de repositorio:
            app.ImgCarga.Visible = 'on';
            app.EditFieldCarga.Visible = 'on';
            app.BotonImagen.Visible = 'off';

            selectedFolder = uigetdir; % Selecionar repositorio de imágenes.
            app.Repositorios = [app.Repositorios, {selectedFolder}]; % Guardar el repositorio actual en la celda.
            [~, folderName, ~] = fileparts(selectedFolder); % Obtener el nombre de la carpeta seleccionada.
            files = dir(selectedFolder); 
            fileNames = {files(~[files.isdir]).name}; % Obtener la lista de imágenes del repositorio.

            parent = uitreenode(app.NodeRepositorio, "Text", folderName); % Crear un nodo con el nombre del repositorio.
            parent.Icon = fullfile('..', 'Imagenes', 'carpeta2.png'); % Añadir el icono de carpeta al lado del nodo del repositorio.

            for i = 1:length(fileNames)
                child = uitreenode(parent, "Text", fileNames{1,i}); % Colocar todas las imágenes del repositorio en el árbol.
                child.Icon = fullfile(selectedFolder, fileNames{1,i}); % Añadir el icono de las imágenes.
            end
            
            app.GlauDTect.Pointer = 'arrow'; % Poner el cursor en modo por defecto.
            % Esconder el aviso de carga de repositorio:
            app.ImgCarga.Visible = 'off';
            app.EditFieldCarga.Visible = 'off';
            app.BotonImagen.Visible = 'on';
        end

        % Image clicked function: ImgCandado
        function ImgCandadoImageClicked2(app, event)
            sound(app.s4, app.Fs4) % Reproducir sonido de cerrar sesión.
            pause(0.5); % Esperar medio segundo.
            delete(app); % Cerrar la aplicación.
        end

        % Selection changed function: TreeRepositorio
        function TreeRepositorioSelectionChanged(app, event)
            selectedNode = app.TreeRepositorio.SelectedNodes; % Seleccionar imagen de un repositorio.

            if isempty(selectedNode.Children) && ~isempty(selectedNode.Parent)
                parentNode = selectedNode.Parent.Text;
                [~, folderNames, ~] = fileparts(app.Repositorios);
                idx = find(strcmp(folderNames, parentNode));
                
                app.DropDownElegirCanal.Value = "Color";
                app.Lamp.Color = [1 1 1];
                app.ImgRFI.ImageSource = imread(fullfile(app.Repositorios{idx}, selectedNode.Text));
                app.ImgActual = app.ImgRFI.ImageSource;

                img = app.ImgActual;
                app.ImgRFI.ImageSource = img;

                img_red = img(:,:,1); % Convertir imagen roja a escala de grises.
                img_red = cat(3, img_red, img_red, img_red); % Convertir imagen roja a formato RGB.
                img_green = img(:,:,2); % Convertir imagen verde a escala de grises.
                img_green = cat(3, img_green, img_green, img_green); % Convertir imagen verde a formato RGB.
                img_blue = img(:,:,3); % Convertir imagen azul a escala de grises.
                img_blue = cat(3, img_blue, img_blue, img_blue); % Convertir imagen azul a formato RGB.
                
                cla(app.Histogram); % Limpiar histograma actual.
                [histograma, intensidades] = imhist(img_red); % Calcular el histograma verde.
                bar(app.Histogram, intensidades, histograma, 'FaceColor', [1 0 0]); % Dibujar el histograma rojo.
                xticks(app.Histogram, [0:25:225, 255]);
                hold(app.Histogram, 'on');

                [histograma, intensidades] = imhist(img_green); % Calcular el histograma verde.
                bar(app.Histogram, intensidades, histograma, 'FaceColor', [0 1 0]); % Dibujar el histograma verde.
                hold(app.Histogram, 'on');

                [histograma, intensidades] = imhist(img_blue); % Calcular el histograma azul.
                bar(app.Histogram, intensidades, histograma, 'FaceColor', [0 0 1]); % Dibujar el histograma azul.
            end
        end

        % Callback function
        function DeterminarcalidadButtonPushed2(app, event)

        end

        % Button pushed function: ButtonLogIn
        function ButtonLogInPushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(1); % Ir al módulo de cribado de calidad.
            
            % Limpiar todos los campos de inicio de sesión:
            app.EditFieldNombreApellidos.Value = '';
            app.EditFieldID.Value = '';
            app.EditFieldCorreo.Value = '';
            app.EditFieldContrasena.Value = '';
            
            % Esconder el campo de inicio de sesión erróneo:
            app.ImgError.Visible = 'off';
            app.EditFieldError.Visible = 'off';
            
            % Esconder el campo de inicio de sesión correcto:
            app.ImgCorrecto.Visible = 'off';
            app.EditFieldCorrecto.Visible = 'off';
        end

        % Button pushed function: ButtonFilter
        function ButtonFilterPushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(2); % Ir al módulo de cribado de calidad.
        end

        % Button pushed function: ButtonGlaucoma
        function ButtonGlaucomaPushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(3); % Ir al módulo de detección de glaucoma.
        end

        % Button pushed function: ButtonClipBoard
        function ButtonClipBoardPushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(4); % Ir al módulo de creación de informe.
        end

        % Button pushed function: ButtonAcercaDe
        function ButtonAcercaDePushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(5); % Ir al módulo de acerca de.
        end

        % Button pushed function: ButtonInicioSesion
        function ButtonInicioSesionPushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            for i = 1:height(app.doctores)
                if strcmp(char(app.doctores{i,1}), app.EditFieldNombreApellidos.Value) && strcmp(char(app.doctores{i,2}), app.EditFieldCorreo.Value) && strcmp(char(app.doctores{i,3}), app.EditFieldID.Value) && strcmp(char(app.doctores{i,4}), app.EditFieldContrasena.Value)
                    sound(app.s1, app.Fs1) % Reproducir sonido de correcto.

                    % Esconder el campo de inicio de sesión erróneo:
                    app.ImgError.Visible = 'off';
                    app.EditFieldError.Visible = 'off';
                    
                    % Enseñar el campo de inicio de sesión correcto:
                    app.ImgCorrecto.Visible = 'on';
                    app.EditFieldCorrecto.Visible = 'on';
                    
                    app.ImgDoctor.ImageSource = imread(fullfile('..', 'Imagenes', [app.EditFieldNombreApellidos.Value, '.png']));
                    pause(0.5); % Esperar medio segundo.
                    app.Modulos.SelectedTab = app.Modulos.Children(2); % Ir al módulo de cribado de calidad.
                    break;
                else
                    sound(app.s2, app.Fs2) % Reproducir sonido de error.

                    % Enseñar el campo de inicio de sesión erróneo:
                    app.ImgError.Visible = 'on';
                    app.EditFieldError.Visible = 'on';
                    
                    % Esconder el campo de inicio de sesión correcto:
                    app.ImgCorrecto.Visible = 'off';
                    app.EditFieldCorrecto.Visible = 'off';
                end
            end
            app.ImgDoctor.Tooltip = ['Dr. ', app.EditFieldNombreApellidos.Value];
            app.LabelDoctor.Text = ['Dr. ', app.EditFieldNombreApellidos.Value];
            app.LabelID.Text = ['ID: ', app.EditFieldID.Value];
        end

        % Value changing function: EditFieldNombreApellidos
        function EditFieldNombreApellidosValueChanging(app, event)
            changingValue = event.Value;
            if ~isempty(changingValue)
                app.Ast1.Visible = 'off';
            else
                app.Ast1.Visible = 'on';
            end
        end

        % Value changing function: EditFieldCorreo
        function EditFieldCorreoValueChanging(app, event)

        end

        % Value changing function: EditFieldContrasena
        function EditFieldContrasenaValueChanging(app, event)
            changingValue = event.Value;
            if ~isempty(changingValue)
                app.Ast3.Visible = 'off';
            else
                app.Ast3.Visible = 'on';
            end
        end

        % Button pushed function: ButtonLimpiar
        function ButtonLimpiarButtonPushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            
            % Limpiar todos los cmpos de inicio de sesión:
            app.EditFieldNombreApellidos.Value = '';
            app.EditFieldID.Value = '';
            app.EditFieldCorreo.Value = '';
            app.EditFieldContrasena.Value = '';
            
            % Enseñar los asteriscos de campo obligatorio:
            app.Ast1.Visible = 'on';
            app.Ast2.Visible = 'on';
            app.Ast3.Visible = 'on';
            
            % Esconder el campo de inicio de sesión erróneo:
            app.ImgError.Visible = 'off';
            app.EditFieldError.Visible = 'off';

            % Esconder el campo de inicio de sesión correcto:
            app.ImgCorrecto.Visible = 'off';
            app.EditFieldCorrecto.Visible = 'off';
        end

        % Value changing function: EditFieldID
        function EditFieldIDValueChanging(app, event)
            changingValue = event.Value;
            if ~isempty(changingValue)
                app.Ast2.Visible = 'off';
            else
                app.Ast2.Visible = 'on';
            end
        end

        % Button pushed function: ButtonDeterminarCalidad
        function ButtonDeterminarCalidadPushed(app, event)
             if ~isempty(app.ImgRFI.ImageSource)
                app.GlauDTect.Pointer = 'watch'; % Poner el cursor en modo espera.
                
                % Llamar a la función para calcular las métricas de calidad de la imagen:
                [y1, y2, y3, y4, y5, y6] = CalcularMetricasCalidad(app, app.ImgActual); % Calcular las métricas de calidad.
                data = {y1, y2, y3, y4, y5, y6};
                T = cell2table(data, 'VariableNames', {'entropy', 'dynamic_range', 'contrast', 'lap_max', 'lap_mean', 'lap_std'});
                app.EntropiaEditField.Value = y1;
                app.RangoDinamicoEditField.Value = double(y2);
                app.ContrasteEditField.Value = y3;
                app.LapMaxEditField.Value = double(y4);
                app.LapMediaEditField.Value = double(y5);
                app.LapStdEditField.Value = double(y6);
                
                ypred = double(app.QualityModel.predictFcn(T));
                app.CalidaddeimagenEditField.Value = app.quality{cellfun(@(x) x == ypred, app.quality(:,1)), 2};

                if app.CalidaddeimagenEditField.Value == "Calidad correcta"
                    sound(app.s1,app.Fs1)
                else
                    sound(app.s2,app.Fs2)
                end
    
                app.GlauDTect.Pointer = 'arrow'; % Poner el cursor en modo por defecto.
            end
        end

        % Value changed function: DropDownElegirCanal
        function DropDownElegirCanalValueChanged(app, event)
            value = app.DropDownElegirCanal.Value; % Guardar el canal elegido.
            if value == "Color"
                app.Lamp.Color = [1 1 1]; % Iluminar la lámpara en blanco.
                if ~isempty(app.ImgRFI.ImageSource)
                    cla(app.Histogram); % Limpiar el histograma actual.

                    img = app.ImgActual; % Obtener la imagen actual.
                    app.ImgRFI.ImageSource = img; % Visualizar la imagen actual.

                    img_red = img(:,:,1); % Convertir imagen roja a escala de grises.
                    img_red = cat(3, img_red, img_red, img_red); % Convertir imagen roja a formato RGB.
                    img_green = img(:,:,2); % Convertir imagen verde a escala de grises.
                    img_green = cat(3, img_green, img_green, img_green); % Convertir imagen verde a formato RGB.
                    img_blue = img(:,:,3); % Convertir imagen azul a escala de grises.
                    img_blue = cat(3, img_blue, img_blue, img_blue); % Convertir imagen azul a formato RGB.

                    [histograma, intensidades] = imhist(img_red); % Calcular el histograma rojo.
                    bar(app.Histogram, intensidades, histograma, 'FaceColor', [1 0 0]); % Dibujar el histograma rojo.
                    hold(app.Histogram, 'on');
                    xticks(app.Histogram, [0:25:225, 255]);

                    [histograma, intensidades] = imhist(img_green); % Calcular el histograma verde.
                    bar(app.Histogram, intensidades, histograma, 'FaceColor', [0 1 0]); % Dibujar el histograma verde.
                    hold(app.Histogram, 'on');

                    [histograma, intensidades] = imhist(img_blue); % Calcular el histograma azul.
                    bar(app.Histogram, intensidades, histograma, 'FaceColor', [0 0 1]); % Dibujar el histograma azul.
                end
            elseif value == "Gris"
                app.Lamp.Color = [0.5 0.5 0.5]; % Iluminar la lámpara en gris.
                if ~isempty(app.ImgRFI.ImageSource)
                    cla(app.Histogram); % Limpiar el histograma actual.
                    img = app.ImgActual;
                    img_gray = im2gray(img); % Convierte a escala de grises
                    img_rgb = cat(3, img_gray, img_gray, img_gray); % Convierte a formato RGB
                    app.ImgRFI.ImageSource = img_rgb;
                    % Calcula el histograma
                    [histograma, intensidades] = imhist(im2gray(app.ImgRFI.ImageSource));
                    % Dibuja el histograma en el UIAxes
                    bar(app.Histogram, intensidades, histograma, 'FaceColor', [0.5 0.5 0.5]);
                    xticks(app.Histogram, [0:25:225, 255]);
                end
            elseif value == "Rojo"
                app.Lamp.Color = [1 0 0]; % Iluminar la lámpara en rojo.
                if ~isempty(app.ImgRFI.ImageSource)
                    cla(app.Histogram); % Limpiar el histograma actual.

                    img = app.ImgActual; % Obtener la imagen actual.
                    img_red = img(:,:,1); % Obtener el canal rojo.
                    img_red = cat(3, img_red, img_red, img_red); % Convierte a formato RGB.
                    app.ImgRFI.ImageSource = img_red; % Visualizar el canal rojo.

                    [histograma, intensidades] = imhist(img_red); % Calcular el histograma rojo.
                    bar(app.Histogram, intensidades, histograma, 'FaceColor', [1 0 0]); % Dibujar el histograma rojo.
                    xticks(app.Histogram, [0:25:225, 255]);
                end
            elseif value == "Verde"
                app.Lamp.Color = [0 1 0]; % Iluminar la lámpara en verde.
                if ~isempty(app.ImgRFI.ImageSource)
                    cla(app.Histogram); % Limpiar el histograma actual.

                    img = app.ImgActual; % Obtener la imagen actual.
                    img_green = img(:,:,2); % Obtener el canal verde
                    img_green = cat(3, img_green, img_green, img_green); % Convierte a formato RGB.
                    app.ImgRFI.ImageSource = img_green; % Visualizar el canal verde.

                    [histograma, intensidades] = imhist(img_green); % Calcular el histograma verde.
                    bar(app.Histogram, intensidades, histograma, 'FaceColor', [0 1 0]); % Dibujar el histograma verde.
                    xticks(app.Histogram, [0:25:225, 255]);
                end
            elseif value == "Azul"
                app.Lamp.Color = [0 0 1]; % Iluminar la lámpara en azul.
                if ~isempty(app.ImgRFI.ImageSource)
                    cla(app.Histogram); % Limpiar el histograma actual.

                    img = app.ImgActual; % Obtener la imagen actual.
                    img_blue = img(:,:,3); % Obtener el canal azul.
                    img_blue = cat(3, img_blue, img_blue, img_blue); % Convierte a formato RGB.
                    app.ImgRFI.ImageSource = img_blue; % Visualizar el canal azul

                    [histograma, intensidades] = imhist(img_blue); % Calcular el histograma azul.
                    bar(app.Histogram, intensidades, histograma, 'FaceColor', [0 0 1]); % Dibujar el histograma azul.
                    xticks(app.Histogram, [0:25:225, 255]);
                end
            end
        end

        % Button pushed function: BotonImagen
        function BotonImagenButtonPushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            
            % Abrir un diálogo para que el usuario elija una imagen
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.gif','All Image Files'}, 'Selecciona una imagen');
            
            % Verificar si se seleccionó una imagen
            if isequal(file,0)
                disp('No se seleccionó ninguna imagen.');
                return;
            end
            
            % Leer la imagen seleccionada
            imagePath = fullfile(path, file);
            
            % Cambiar la imagen del componente de imagen
            app.ImgRFI.ImageSource = imread(imagePath); % Cambia la imagen en el componente
            app.ImgActual = app.ImgRFI.ImageSource;
            img = app.ImgActual;

            app.DropDownElegirCanal.Value = "Color";
            app.Lamp.Color = [1 1 1];

            img_red = img(:,:,1); % Convertir imagen roja a escala de grises.
            img_red = cat(3, img_red, img_red, img_red); % Convertir imagen roja a formato RGB.
            img_green = img(:,:,2); % Convertir imagen verde a escala de grises.
            img_green = cat(3, img_green, img_green, img_green); % Convertir imagen verde a formato RGB.
            img_blue = img(:,:,3); % Convertir imagen azul a escala de grises.
            img_blue = cat(3, img_blue, img_blue, img_blue); % Convertir imagen azul a formato RGB.
                
            cla(app.Histogram); % Limpiar histograma actual.
            [histograma, intensidades] = imhist(img_red); % Calcular el histograma verde.
            bar(app.Histogram, intensidades, histograma, 'FaceColor', [1 0 0]); % Dibujar el histograma rojo.
            xticks(app.Histogram, [0:25:225, 255]);
            hold(app.Histogram, 'on');

            [histograma, intensidades] = imhist(img_green); % Calcular el histograma verde.
            bar(app.Histogram, intensidades, histograma, 'FaceColor', [0 1 0]); % Dibujar el histograma verde.
            hold(app.Histogram, 'on');

            [histograma, intensidades] = imhist(img_blue); % Calcular el histograma azul.
            bar(app.Histogram, intensidades, histograma, 'FaceColor', [0 0 1]); % Dibujar el histograma azul.
        end

        % Button pushed function: ButtonAcercaDe1
        function ButtonAcercaDe1Pushed(app, event)
            sound(app.s3, app.Fs3) % Reproducir sonido de botón.
            app.Modulos.SelectedTab = app.Modulos.Children(5); % Ir al módulo de acerca de.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GlauDTect = uifigure('Visible', 'off');
            app.GlauDTect.Name = 'MATLAB App';
            app.Modulos = uitabgroup(app.GlauDTect);
            app.IniciarSesion = uitab(app.Modulos);
            app.IniciarSesion.Title = 'IniciarSesion';
            app.ImgFondo = uiimage(app.IniciarSesion);
            app.LabelSuperior = uilabel(app.IniciarSesion);
            app.LabelSuperior.Text = 'GLAU D-TECT: SOFTWARE DE AYUDA AL DIAGNÓSTICO DEL GLAUCOMA';
            app.LabelBordeSuperior = uilabel(app.IniciarSesion);
            app.LabelBordeSuperior.Text = '0';
            app.HTMLCuadrado = uihtml(app.IniciarSesion);
            app.LabelBienvenido = uilabel(app.IniciarSesion);
            app.LabelBienvenido.Text = '¡Bienvenido!';
            app.ImgLogo1 = uiimage(app.IniciarSesion);
            app.LabelInicioDeSesion = uilabel(app.IniciarSesion);
            app.LabelInicioDeSesion.Text = 'Inicio de Sesión';
            app.Ast1 = uilabel(app.IniciarSesion);
            app.Ast1.Tooltip = {'¡Campo obligatorio!'};
            app.Ast1.Text = '*';
            app.EditFieldNombreApellidos = uieditfield(app.IniciarSesion, 'text');
            app.EditFieldNombreApellidos.ValueChangingFcn = createCallbackFcn(app, @EditFieldNombreApellidosValueChanging, true);
            app.EditFieldNombreApellidos.Tooltip = {'Introduzca su nombre y apellidos por favor.'};
            app.EditFieldNombreApellidos.Placeholder = 'Nombre y Apellidos';
            app.ImgNombreApellidos = uiimage(app.IniciarSesion);
            app.ImgNombreApellidos.Tooltip = {'Introduzca su nombre y apellidos por favor.'};
            app.Ast2 = uilabel(app.IniciarSesion);
            app.Ast2.Tooltip = {'¡Campo obligatorio!'};
            app.Ast2.Text = '*';
            app.EditFieldID = uieditfield(app.IniciarSesion, 'text');
            app.EditFieldID.ValueChangingFcn = createCallbackFcn(app, @EditFieldIDValueChanging, true);
            app.EditFieldID.Tooltip = {'Introduzca su código ID por favor.'};
            app.EditFieldID.Placeholder = 'Código ID';
            app.EditFieldCorreo = uieditfield(app.IniciarSesion, 'text');
            app.EditFieldCorreo.ValueChangingFcn = createCallbackFcn(app, @EditFieldCorreoValueChanging, true);
            app.EditFieldCorreo.Tooltip = {'Introduzca su correo electrónico por favor.'};
            app.EditFieldCorreo.Placeholder = 'Correo Electrónico';
            app.ImgID = uiimage(app.IniciarSesion);
            app.ImgID.Tooltip = {'Introduzca su código ID por favor.'};
            app.ImgCorreo = uiimage(app.IniciarSesion);
            app.ImgCorreo.Tooltip = {'Introduzca su correo electrónico por favor.'};
            app.Ast3 = uilabel(app.IniciarSesion);
            app.Ast3.Tooltip = {'¡Campo obligatorio!'};
            app.Ast3.Text = '*';
            app.EditFieldContrasena = uieditfield(app.IniciarSesion, 'text');
            app.EditFieldContrasena.ValueChangingFcn = createCallbackFcn(app, @EditFieldContrasenaValueChanging, true);
            app.EditFieldContrasena.Tooltip = {'Introduzca su contraseña por favor.'};
            app.EditFieldContrasena.Placeholder = 'Contraseña';
            app.ImgContrasena = uiimage(app.IniciarSesion);
            app.ImgContrasena.Tooltip = {'Introduzca su contraseña por favor.'};
            app.EditFieldError = uilabel(app.IniciarSesion);
            app.EditFieldError.Visible = 'off';
            app.EditFieldError.Text = 'ERROR: ¡Las credenciales de acceso no están registradas!  ';
            app.EditFieldCorrecto = uilabel(app.IniciarSesion);
            app.EditFieldCorrecto.Visible = 'off';
            app.EditFieldCorrecto.Text = 'Las credenciales de acceso introducidas son correctas.       ';
            app.ImgError = uiimage(app.IniciarSesion);
            app.ImgError.Visible = 'off';
            app.ImgCorrecto = uiimage(app.IniciarSesion);
            app.ImgCorrecto.Visible = 'off';
            app.ButtonInicioSesion = uibutton(app.IniciarSesion, 'push');
            app.ButtonInicioSesion.ButtonPushedFcn = createCallbackFcn(app, @ButtonInicioSesionPushed, true);
            app.ButtonInicioSesion.Icon = fullfile(pathToMLAPP, '..', 'Imagenes', 'inicio_sesion.png');
            app.ButtonInicioSesion.Tooltip = {'Pulse para iniciar sesión.'};
            app.ButtonInicioSesion.Text = ' Iniciar Sesión';
            app.ButtonLimpiar = uibutton(app.IniciarSesion, 'push');
            app.ButtonLimpiar.ButtonPushedFcn = createCallbackFcn(app, @ButtonLimpiarButtonPushed, true);
            app.ButtonLimpiar.Icon = fullfile(pathToMLAPP, '..', 'Imagenes', 'basura.png');
            app.ButtonLimpiar.Tooltip = {'Pulse para limpiar los campos.'};
            app.ButtonLimpiar.Text = '   Limpiar ';
            app.LabelBordeInferior = uilabel(app.IniciarSesion);
            app.LabelBordeInferior.Text = '0';
            app.LabelInferior = uilabel(app.IniciarSesion);
            app.LabelInferior.Text = 'Ingeniería Biomédica - POPBL 6 - Grupo 6';
            app.ButtonAcercaDe1 = uibutton(app.IniciarSesion, 'push');
            app.ButtonAcercaDe1.ButtonPushedFcn = createCallbackFcn(app, @ButtonAcercaDe1Pushed, true);
            app.ButtonAcercaDe1.Icon = fullfile(pathToMLAPP, '..', 'Imagenes', 'acercade1.png');
            app.ButtonAcercaDe1.Tooltip = {'Pulse para limpiar los campos.'};
            app.ButtonAcercaDe1.Text = 'Acerca De';
            app.CribadoCalidad = uitab(app.Modulos);
            app.CribadoCalidad.Title = 'CribadoCalidad';
            app.Histogram = uiaxes(app.CribadoCalidad);
            title(app.Histogram, 'Histograma')
            xlabel(app.Histogram, 'Intensidad de píxel')
            ylabel(app.Histogram, 'Frecuencia de píxel')
            app.Histogram.XTick = [0 25 50 75 100 125 150 175 200 225 255];
            app.Histogram.XTickLabel = {'0'; '25'; '50'; '75'; '100'; '125'; '150'; '175'; '200'; '225'; '255'};
            app.Titulo = uilabel(app.CribadoCalidad);
            app.Titulo.Text = '';
            app.HTMLFechaHora = uihtml(app.CribadoCalidad);
            app.HTMLFechaHora.HTMLSource = '../html/fecha_hora.html';
            app.ImgDoctor = uiimage(app.CribadoCalidad);
            app.ImgDoctor.Tooltip = {''};
            app.ButtonLogIn = uibutton(app.CribadoCalidad, 'push');
            app.ButtonLogIn.ButtonPushedFcn = createCallbackFcn(app, @ButtonLogInPushed, true);
            app.ButtonLogIn.Icon = fullfile(pathToMLAPP, '..', 'Imagenes', 'inicio_sesion2.png');
            app.ButtonLogIn.Tooltip = {'Pulse para ir al módulo de inicio de sesión.'};
            app.ButtonLogIn.Text = '';
            app.ButtonFilter = uibutton(app.CribadoCalidad, 'push');
            app.ButtonFilter.ButtonPushedFcn = createCallbackFcn(app, @ButtonFilterPushed, true);
            app.ButtonFilter.Icon = fullfile(pathToMLAPP, '..', 'Imagenes', 'filtro.png');
            app.ButtonFilter.Tooltip = {'Pulse para ir al módulo de cribado de calidad.'};
            app.ButtonFilter.Text = '';
            app.ButtonGlaucoma = uibutton(app.CribadoCalidad, 'push');
            app.ButtonGlaucoma.ButtonPushedFcn = createCallbackFcn(app, @ButtonGlaucomaPushed, true);
            app.ButtonGlaucoma.Icon = fullfile(pathToMLAPP, '..', 'Imagenes', 'ojo2.png');
            app.ButtonGlaucoma.Tooltip = {'Pulse para ir al módulo de detección de glaucoma.'};
            app.ButtonGlaucoma.Text = '';
            app.ButtonClipBoard = uibutton(app.CribadoCalidad, 'push');
            app.ButtonClipBoard.ButtonPushedFcn = createCallbackFcn(app, @ButtonClipBoardPushed, true);
            app.ButtonClipBoard.Icon = fullfile(pathToMLAPP, '..', 'Imagenes', 'Portapapeles.png');
            app.ButtonClipBoard.Tooltip = {'Pulse para ir al módulo de creación de informe.'};
            app.ButtonClipBoard.Text = '';
            app.ButtonAcercaDe = uibutton(app.CribadoCalidad, 'push');
            app.ButtonAcercaDe.ButtonPushedFcn = createCallbackFcn(app, @ButtonAcercaDePushed, true);
            app.ButtonAcercaDe.Icon = fullfile(pathToMLAPP, '..', 'Imagenes', 'acercade.png');
            app.ButtonAcercaDe.Tooltip = {'Pulse para ir al módulo de acerca de.'};
            app.ButtonAcercaDe.Text = '';
            app.LabelDoctor = uilabel(app.CribadoCalidad);
            app.LabelDoctor.Text = '';
            app.ImgLogo2 = uiimage(app.CribadoCalidad);
            app.ImgLogo2.ImageSource = fullfile(pathToMLAPP, '..', 'Imagenes', 'logo.png');
            app.LabelID = uilabel(app.CribadoCalidad);
            app.LabelID.Text = '';
            app.ImgCandado = uiimage(app.CribadoCalidad);
            app.ImgCandado.ImageClickedFcn = createCallbackFcn(app, @ImgCandadoImageClicked2, true);
            app.ImgCandado.Tooltip = {'Pulse para cerrar sesión.'};
            app.CerrarSesinLabel = uilabel(app.CribadoCalidad);
            app.CerrarSesinLabel.Text = 'Cerrar Sesión';
            app.DeteccinGlaucomaLabel_2 = uilabel(app.CribadoCalidad);
            app.DeteccinGlaucomaLabel_2.Text = {'Detección'; 'Glaucoma'};
            app.ImgPaginaWeb = uiimage(app.CribadoCalidad);
            app.ImgPaginaWeb.Tooltip = {'Pulse para acceder a la página web.'};
            app.PaginaWebLabel = uilabel(app.CribadoCalidad);
            app.PaginaWebLabel.Text = 'Página Web';
            app.IniciarSesinLabel = uilabel(app.CribadoCalidad);
            app.IniciarSesinLabel.Text = 'Iniciar Sesión';
            app.CribadoCalidadLabel = uilabel(app.CribadoCalidad);
            app.CribadoCalidadLabel.Text = 'Cribado Calidad';
            app.CrearInformeLabel_2 = uilabel(app.CribadoCalidad);
            app.CrearInformeLabel_2.Text = 'Crear Informe';
            app.AcercadeLabel = uilabel(app.CribadoCalidad);
            app.AcercadeLabel.Text = 'Acerca de';
            app.Label = uilabel(app.CribadoCalidad);
            app.Label.Text = '0';
            app.ElegirCanaldeimagenLabel = uilabel(app.CribadoCalidad);
            app.ElegirCanaldeimagenLabel.Tooltip = {'Seleccione si desea visualizar la imagen a color, en escala de grises o si desea visualizar los diferentes canales de la imagen.'};
            app.ElegirCanaldeimagenLabel.Text = 'Elegir Canal:';
            app.DropDownElegirCanal = uidropdown(app.CribadoCalidad);
            app.DropDownElegirCanal.Items = {'Color', 'Gris', 'Rojo', 'Verde', 'Azul'};
            app.DropDownElegirCanal.ValueChangedFcn = createCallbackFcn(app, @DropDownElegirCanalValueChanged, true);
            app.DropDownElegirCanal.Tooltip = {'Seleccione si desea visualizar la imagen a color, en escala de grises o si desea visualizar los diferentes canales de la imagen.'};
            app.DropDownElegirCanal.Value = 'Color';
            app.Lamp = uilamp(app.CribadoCalidad);
            app.ImgRFI = uiimage(app.CribadoCalidad);
            app.ImgRFI.Tooltip = {'Imágen de fondo de ojo.'};
            app.ButtonDeterminarCalidad = uibutton(app.CribadoCalidad, 'push');
            app.ButtonDeterminarCalidad.ButtonPushedFcn = createCallbackFcn(app, @ButtonDeterminarCalidadPushed, true);
            app.ButtonDeterminarCalidad.Icon = fullfile(pathToMLAPP, '..', 'Imagenes', 'calidad.png');
            app.ButtonDeterminarCalidad.Tooltip = {'Pulse para determinar la calidad de la imagen.'};
            app.ButtonDeterminarCalidad.Text = 'Determinar calidad';
            app.CalidaddeimagenEditFieldLabel = uilabel(app.CribadoCalidad);
            app.CalidaddeimagenEditFieldLabel.Text = 'Calidad de imagen';
            app.CalidaddeimagenEditField = uieditfield(app.CribadoCalidad, 'text');
            app.EntropiaEditFieldLabel = uilabel(app.CribadoCalidad);
            app.EntropiaEditFieldLabel.Text = 'Entropia';
            app.EntropiaEditField = uieditfield(app.CribadoCalidad, 'numeric');
            app.LapMaxEditFieldLabel = uilabel(app.CribadoCalidad);
            app.LapMaxEditFieldLabel.Text = 'Lap Max';
            app.LapMaxEditField = uieditfield(app.CribadoCalidad, 'numeric');
            app.RangoDinamicoEditFieldLabel = uilabel(app.CribadoCalidad);
            app.RangoDinamicoEditFieldLabel.Text = 'Rango Dinamico';
            app.RangoDinamicoEditField = uieditfield(app.CribadoCalidad, 'numeric');
            app.LapStdEditFieldLabel = uilabel(app.CribadoCalidad);
            app.LapStdEditFieldLabel.Text = 'Lap Std';
            app.LapStdEditField = uieditfield(app.CribadoCalidad, 'numeric');
            app.LapMediaEditFieldLabel = uilabel(app.CribadoCalidad);
            app.LapMediaEditFieldLabel.Text = 'Lap Media';
            app.LapMediaEditField = uieditfield(app.CribadoCalidad, 'numeric');
            app.ContrasteEditFieldLabel = uilabel(app.CribadoCalidad);
            app.ContrasteEditFieldLabel.Text = 'Contraste';
            app.ContrasteEditField = uieditfield(app.CribadoCalidad, 'numeric');
            app.TreeRepositorio = uitree(app.CribadoCalidad);
            app.TreeRepositorio.SelectionChangedFcn = createCallbackFcn(app, @TreeRepositorioSelectionChanged, true);
            app.NodeRepositorio = uitreenode(app.TreeRepositorio);
            app.NodeRepositorio.Icon = fullfile(pathToMLAPP, '..', 'Imagenes', 'imagen.png');
            app.NodeRepositorio.Text = 'Repositorios de imágenes:';
            app.BotonAnadirRepositorio = uibutton(app.CribadoCalidad, 'push');
            app.BotonAnadirRepositorio.ButtonPushedFcn = createCallbackFcn(app, @BotonAnadirRepositorioPushed, true);
            app.BotonAnadirRepositorio.Icon = fullfile(pathToMLAPP, '..', 'Imagenes', 'carpeta.png');
            app.BotonAnadirRepositorio.Tooltip = {'Pulse para seleccionar y añadir un repositorio de imágenes.'};
            app.BotonAnadirRepositorio.Text = 'Añadir repositorio';
            app.EditFieldCarga = uilabel(app.CribadoCalidad);
            app.EditFieldCarga.Text = '         ¡Cargando el repositorio!  ';
            app.ImgCarga = uiimage(app.CribadoCalidad);
            app.ImgCarga.ImageSource = fullfile(pathToMLAPP, '..', 'Imagenes', 'carga.gif');
            app.Titulo_2 = uilabel(app.CribadoCalidad);
            app.Titulo_2.Text = 'Ingeniería Biomédica - POPBL 6 - Grupo 6';
            app.Label_2 = uilabel(app.CribadoCalidad);
            app.Label_2.Text = '0';
            app.BotonImagen = uibutton(app.CribadoCalidad, 'push');
            app.BotonImagen.ButtonPushedFcn = createCallbackFcn(app, @BotonImagenButtonPushed, true);
            app.BotonImagen.Icon = fullfile(pathToMLAPP, '..', 'Imagenes', 'imagen2.png');
            app.BotonImagen.Tooltip = {'Pulse para seleccionar y añadir un repositorio de imágenes.'};
            app.BotonImagen.Text = 'Añadir imagen';
            app.DeteccionGlaucoma = uitab(app.Modulos);
            app.DeteccionGlaucoma.Title = 'DeteccionGlaucoma';
            app.CrearInforme = uitab(app.Modulos);
            app.CrearInforme.Title = 'CreacionInforme';
            app.AcercaDe = uitab(app.Modulos);
            app.AcercaDe.Title = 'AcercaDe';
            % Show the figure after all components are created
            app.GlauDTect.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Principal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GlauDTect)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GlauDTect)
        end
    end
end